classdef TireAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TireAnalyzerUIFigure           matlab.ui.Figure
        PlotsPanel                     matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        PacejkaFitsPanel               matlab.ui.container.Panel
        PpsiLabel                      matlab.ui.control.Label
        PacejkaPDropDown               matlab.ui.control.DropDown
        IAdegLabel                     matlab.ui.control.Label
        PacejkaIADropDown              matlab.ui.control.DropDown
        FZNLabel                       matlab.ui.control.Label
        PacejkaFZDropDown              matlab.ui.control.DropDown
        SAdirDropDownLabel             matlab.ui.control.Label
        PacejkaSAdirDropDown           matlab.ui.control.DropDown
        PacejkaClickRunLabel           matlab.ui.control.Label
        FitnotcompletedLabel           matlab.ui.control.Label
        PacejkaFitAxes                 matlab.ui.control.UIAxes
        CamberSensitivityPanel         matlab.ui.container.Panel
        NotimplementedyetLabel         matlab.ui.control.Label
        PressureSensitivityPanel       matlab.ui.container.Panel
        NotimplementedyetLabel_2       matlab.ui.control.Label
        LoadSensitivityPanel           matlab.ui.container.Panel
        PpsiDropDown_2Label            matlab.ui.control.Label
        IAdegDropDown_2Label           matlab.ui.control.Label
        SAdirDropDownLabel_2           matlab.ui.control.Label
        SASliderPanel                  matlab.ui.container.Panel
        UBdegLabel                     matlab.ui.control.Label
        SASlider                       matlab.ui.control.Slider
        LBdegLabel                     matlab.ui.control.Label
        FZSAdirDropDown                matlab.ui.control.DropDown
        FixDropDownLabel               matlab.ui.control.Label
        FixDropDown                    matlab.ui.control.DropDown
        FZPDropDown                    matlab.ui.control.DropDown
        FZIADropDown                   matlab.ui.control.DropDown
        LoadClickRunLabel              matlab.ui.control.Label
        FZAxes                         matlab.ui.control.UIAxes
        StatusPanel                    matlab.ui.container.Panel
        PrimaryStatusLabel             matlab.ui.control.Label
        SecondaryStatusLabel           matlab.ui.control.Label
        TertiaryStatusLabel            matlab.ui.control.Label
        TitlePanel                     matlab.ui.container.Panel
        TireAnalyzerLabel              matlab.ui.control.Label
        ByMatthewDrummondStoylesLabel  matlab.ui.control.Label
        RFRImage                       matlab.ui.control.Image
        RunOptionsPanel                matlab.ui.container.Panel
        TireDropDownLabel              matlab.ui.control.Label
        TireDropDown                   matlab.ui.control.DropDown
        RunButton                      matlab.ui.control.Button
        CreatefitsCheckBox             matlab.ui.control.CheckBox
        FitOptionsPanel                matlab.ui.container.Panel
        LoadsensitivityorderLabel      matlab.ui.control.Label
        LoadSensitivityOrderSpinner    matlab.ui.control.Spinner
        SlipanglebounddegLabel         matlab.ui.control.Label
        SABoundEditField               matlab.ui.control.NumericEditField
        SlipanglestepsizedegEditFieldLabel  matlab.ui.control.Label
        SAStepSizeEditField            matlab.ui.control.NumericEditField
        LoadstepsizeNEditFieldLabel    matlab.ui.control.Label
        LoadStepSizeEditField          matlab.ui.control.NumericEditField
        LoadupperboundNEditFieldLabel  matlab.ui.control.Label
        LoadUBEditField                matlab.ui.control.NumericEditField
        PacejkafitmaxiterationsEditFieldLabel  matlab.ui.control.Label
        PacejkafitmaxiterationsEditField  matlab.ui.control.NumericEditField
        PacejkaalgorithmDropDownLabel  matlab.ui.control.Label
        PacejkaalgorithmDropDown       matlab.ui.control.DropDown
        SeparateSACheckBox             matlab.ui.control.CheckBox
        ButtonGroup                    matlab.ui.container.ButtonGroup
        SelectfromdropdownButton       matlab.ui.control.RadioButton
        SelectindividualparametersButton  matlab.ui.control.RadioButton
        RoundDropDownLabel             matlab.ui.control.Label
        RoundDropDown                  matlab.ui.control.DropDown
        ManufacturerDropDownLabel      matlab.ui.control.Label
        ManufacturerDropDown           matlab.ui.control.DropDown
        OuterdiameterDropDownLabel     matlab.ui.control.Label
        OuterdiameterDropDown          matlab.ui.control.DropDown
        TirewidthDropDownLabel         matlab.ui.control.Label
        TirewidthDropDown              matlab.ui.control.DropDown
        RimouterdiameterDropDownLabel  matlab.ui.control.Label
        RimouterdiameterDropDown       matlab.ui.control.DropDown
        ScalingfactorsPanel            matlab.ui.container.Panel
        UsedifferentXandYscalingfactorsCheckBox  matlab.ui.control.CheckBox
        ScalingfactorLabel             matlab.ui.control.Label
        XscalingfactorEditField        matlab.ui.control.NumericEditField
        ForcecoefficientYscalingfactorEditFieldLabel  matlab.ui.control.Label
        YscalingfactorEditField        matlab.ui.control.NumericEditField
        CompoundLabel                  matlab.ui.control.Label
        CompoundDropDown               matlab.ui.control.DropDown
        RimwidthDropDownLabel          matlab.ui.control.Label
        RimwidthDropDown               matlab.ui.control.DropDown
        RoundCheckBox                  matlab.ui.control.CheckBox
        ManufacturerCheckBox           matlab.ui.control.CheckBox
        ODCheckbox                     matlab.ui.control.CheckBox
        TireWidthCheckBox              matlab.ui.control.CheckBox
        RimODCheckBox                  matlab.ui.control.CheckBox
        CompoundCheckBox               matlab.ui.control.CheckBox
        RimWidthCheckBox               matlab.ui.control.CheckBox
        SaveFyandMzdataCheckBox        matlab.ui.control.CheckBox
    end

    
    % ----- INFO -----
    % 1. Indexing goes: (sa, sa_dir, load, camber, pressure)
    %
    % ----------------
    
    properties (Access = private)
        % Settings
        SABounds % Slip angle bounds
        LoadBounds % Load bounds
        d_SA % Slip angle step size
        d_load % Load step size
        
        % Data files info
        n_files % Number of data files
        n_tires
        FileNames
        TireNamesRaw
        TireNames
        TireInfoRaw cell
        TireInfoMap containers.Map
        TireDataMap containers.Map % Tire data variables
        TireDataRaw cell
        TireNamesSorted cell % Tire names in order of which was found first
        UsedTireData struct % Ones actually used
        FileStartIndices
        StartIndex
        P_lb
        P_ub
        IA_lb
        IA_ub
        FZ_lb
        FZ_ub
        NFY_groups
        SA_groups
        PacejkaDataAxesHandle
        PacejkaFitAxesHandle
        LoadSensAxesDataHandle
        LoadSensAxesFitHandle
        CamberSensAxesHandle
        PressureSensAxesHandle
        DoFits
        SeparateSA
        PacejkaCoeff
        PacejkaVals
        LoadPolyCoeff
        mean_loads
        SolverExitFlags
        SA_Vals double
        Load_Vals
        LatCoeffVals
        Mz_groups
        MzPacejkaCoeff
        MzPacejkaVals
        Mz_Vals
        MzExitFlags
        MzLoadPolyCoeff
        Round
        TestSectionUpperBounds cell
        RunOptions struct = struct( ...
            'SaveData', false, ...
            'UseDiffScalingFactors', false, ...
            'XScalingFactor', 1, ...
            'YScalingFactor', 1, ...
            'CreateFits', true, ...
            'SeparateSADir', false, ...
            'SABound', 15, ...
            'SAStepSize', 0.1, ...
            'PacejkaMaxIterations', 3000, ...
            'PacejkaAlgorithm', 'levenberg-marquardt', ...
            'LoadStepSize', 20, ...
            'LoadUpperBound', 1200, ...
            'LoadSensOrder', 2 ...
            )
    end
    
    properties (Constant)
        TestSectionActions containers.Map = containers.Map( ...
            {'Inc angles SA sweeps', ...
            'Pressures SA sweeps', ...
            'Main SA sweeps'}, ...
            {SASweepsAction(), ...
            SASweepsAction(), ...
            SASweepsAction()});
    end
    
    methods (Access = private)
        
        function [lb, ub] = fit_load_groups(app, start_i, end_i)
            
        end
        
        % Algorithm for finding the index where the regular SA sweeps start
        function index = find_start_index(app)
            counter = 0;
            start_again = false;
            n_data = length(app.UsedTireData.ET);
            disp(strcat('Number of points: ', num2str(n_data)))
            disp(strcat('Tire name: ', app.UsedTireData.tireid))
            
            % Iterate over all the data
            for i = 1:n_data
                
                % Check if abs value of point is less than 0.1
                if abs(app.UsedTireData.IA(i)) < 0.1
                    counter = counter + 1;
                else % If not, reset the counter
                    counter = 0;
                end
                
                % Outside of first flat zone?
                if abs(app.UsedTireData.IA(i)) > 0.1
                    disp('Exited first flat zone')
                    start_again = true;
                    counter = 0;
                end
                
                % Only if this is the 1000th point that's less than 0.1
                % AND if this is the second flat zone, not first
                if counter == 1000 && start_again
                    index = i - 1000; % Where we started counting
                    return;
                end
                
                if i == n_data
                    error('Matt error: no start index found');
                end
            end
        end
        
        function separate_data(app, x_scaling_factor, y_scaling_factor)
            
            [app.P_lb, app.P_ub] = fit_pressure_groups(app.UsedTireData.P, app.StartIndex, length(app.UsedTireData.ET));
            
            for p_counter = 1:3
                [app.IA_lb(:, p_counter), app.IA_ub(:, p_counter)] = fit_camber_groups(app.UsedTireData.IA, app.P_lb(p_counter), app.P_ub(p_counter));
                
                for ia_counter = 1:3
                    % Haven't put load grouping into its own function since the first one has to be done separately
                    
                    % First group has an extra load group, so do it separately
                    if ia_counter == 1
                        [fz_lb, fz_ub] = group_data_diff( ...
                            app.UsedTireData.FZ(app.IA_lb(1, p_counter):app.IA_ub(1, p_counter)), ...
                            6, ...
                            160, ...
                            app.IA_lb(1, p_counter) - 1);
                        %title("FZ grouping for P=" + p_counter + ", IA=0");
                        app.FZ_lb(:, 1, p_counter) = fz_lb(2:6);
                        app.FZ_ub(:, 1, p_counter) = fz_ub(2:6);
                    else
                        [app.FZ_lb(:, ia_counter, p_counter), app.FZ_ub(:, ia_counter, p_counter)] = ...
                            group_data_diff( ...
                            app.UsedTireData.FZ(app.IA_lb(ia_counter, p_counter):app.IA_ub(ia_counter, p_counter)), ...
                            5, ...
                            160, ...
                            app.IA_lb(ia_counter, p_counter) - 1);
                        %title("FZ grouping for P=" + p_counter + ", IA=" + 2*(ia_counter - 1));
                    end
                    
                    for fz_counter = 1:5
                        
                        start_i = app.FZ_lb(fz_counter, ia_counter, p_counter);
                        end_i = app.FZ_ub(fz_counter, ia_counter, p_counter);
                        
                        if p_counter == 1 && ia_counter == 1 && fz_counter == 2
                            disp(['Start i: ' num2str(start_i)]);
                            disp(['End i: ' num2str(end_i)]);
                        end
                        
                        if app.SeparateSA
                            [index_1, index_2] = fit_sa_ranges_run(app.UsedTireData.SA, start_i, end_i);
                            %if p_counter == 1 && ia_counter == 1 && fz_counter == 2
                                %disp(['start_i: ' num2str(start_i)])
                                %disp(['Index 1: ' num2str(index_1)]);
                                %disp(['Index 2: ' num2str(index_2)]);
                                %disp(['end_i: ' num2str(end_i)])
                            %end
                            
                            % NFY where SA is increasing is the first and third sections of the grouped SA data
                            nfy_inc_part1 = app.UsedTireData.NFY(start_i:index_1);
                            nfy_inc_part2 = app.UsedTireData.NFY(index_2:end_i);
                            app.NFY_groups{1, fz_counter, ia_counter, p_counter} = y_scaling_factor*[nfy_inc_part1; nfy_inc_part2];
                            
                            app.NFY_groups{2, fz_counter, ia_counter, p_counter} = y_scaling_factor*app.UsedTireData.NFY(index_1:index_2);
                            
                            sa_inc_part1 = app.UsedTireData.SA(start_i:index_1);
                            sa_inc_part2 = app.UsedTireData.SA(index_2:end_i);
                            app.SA_groups{1, fz_counter, ia_counter, p_counter} = x_scaling_factor*[sa_inc_part1; sa_inc_part2];
                            
                            app.SA_groups{2, fz_counter, ia_counter, p_counter} = x_scaling_factor*app.UsedTireData.SA(index_1:index_2);
                        else
                            app.SA_groups{fz_counter, ia_counter, p_counter} = x_scaling_factor*app.UsedTireData.SA(start_i:end_i);
                            app.NFY_groups{fz_counter, ia_counter, p_counter} = y_scaling_factor*app.UsedTireData.NFY(start_i:end_i);
                            if app.SaveFyandMzdataCheckBox.Value
                                app.Mz_groups{fz_counter, ia_counter, p_counter} = y_scaling_factor*app.UsedTireData.MZ(start_i:end_i);
                            end
                        end
                    end % End of load loop (camber-specific)
                end % End of camber group (pressure-specific)
            end % End of pressure loop (run once)
            
            disp(['Start: ' num2str(app.IA_lb(1, 1))])
            disp(['End: ' num2str(app.IA_ub(1, 1))])
        end
        
        function fit_data(app, algorithm, max_iterations, SA_bounds, delta_SA, load_bounds, delta_load, load_order)
            
            SA_vals = SA_bounds(1):delta_SA:SA_bounds(2);
            load_vals = load_bounds(1):delta_load:load_bounds(2);
            
            n_SA = length(SA_vals);
            n_load = length(load_vals);
            
            for p_counter = 1:3
                
                app.PrimaryStatusLabel.Text = strcat('Fitting pressure group ', num2str(p_counter), '/3');
                
                for ia_counter = 1:3
                    
                    app.SecondaryStatusLabel.Text = strcat('Fitting camber group ', num2str(ia_counter), '/3');
                    
                    app.mean_loads(:, ia_counter, p_counter) = zeros(1, 5);
                    for i = 1:5
                        app.mean_loads(i, ia_counter, p_counter) = -mean(app.UsedTireData.FZ(app.FZ_lb(i, ia_counter, p_counter):app.FZ_ub(i, ia_counter, p_counter)));
                    end
                    
                    % Pacejka fits
                    for fz_counter = 1:5
                        
                        app.TertiaryStatusLabel.Text = strcat('Fitting load group ', num2str(fz_counter), '/5');
                        update_ui(app);
                        
                        if app.SeparateSA
                            % Get values from inc array
                            alpha = app.SA_groups{1, fz_counter, ia_counter, p_counter};
                            mu = app.NFY_groups{1, fz_counter, ia_counter, p_counter};
                            % Fit the data and get the coefficients
                            [C, exitflag] = fit_pacejka(alpha, mu, algorithm, max_iterations);
                            app.PacejkaCoeff(:, 1, fz_counter, ia_counter, p_counter) = C;
                            app.SolverExitFlags(1, fz_counter, ia_counter, p_counter) = exitflag;
                            
                            % Get values from dec array
                            alpha = app.SA_groups{2, fz_counter, ia_counter, p_counter};
                            mu = app.NFY_groups{2, fz_counter, ia_counter, p_counter};
                            % Fit the data and get the coefficients
                            [C, exitflag] = fit_pacejka(alpha, mu, algorithm, max_iterations);
                            app.PacejkaCoeff(:, 2, fz_counter, ia_counter, p_counter) = C;
                            app.SolverExitFlags(2, fz_counter, ia_counter, p_counter) = exitflag;
                            
                            % Take sample points of each pacejka function
                            app.PacejkaVals(:, 1, fz_counter, ia_counter, p_counter) = pacejka(app.PacejkaCoeff(:, 1, fz_counter, ia_counter, p_counter), deg2rad(SA_vals));
                            app.PacejkaVals(:, 2, fz_counter, ia_counter, p_counter) = pacejka(app.PacejkaCoeff(:, 2, fz_counter, ia_counter, p_counter), deg2rad(SA_vals));
                            
                            if p_counter == 1 && ia_counter == 1 && fz_counter == 2
                                disp('Inc: ')
                                app.PacejkaCoeff(:, 1, 2, 1, 1)
                                disp('Dec: ')
                                app.PacejkaCoeff(:, 2, 2, 1, 1)
                            end
                        else
                            alpha = app.SA_groups{fz_counter, ia_counter, p_counter};
                            mu = app.NFY_groups{fz_counter, ia_counter, p_counter};
                            % Fit the data and get the coefficients
                            [C, exitflag] = fit_pacejka(alpha, mu, algorithm, max_iterations);
                            app.PacejkaCoeff(:, fz_counter, ia_counter, p_counter) = C;
                            app.SolverExitFlags(fz_counter, ia_counter, p_counter) = exitflag;
                            
                            % Sample points on the fitted Pacejka curve
                            app.PacejkaVals(:, fz_counter, ia_counter, p_counter) = pacejka(app.PacejkaCoeff(:, fz_counter, ia_counter, p_counter), deg2rad(SA_vals));
                            
                            if app.SaveFyandMzdataCheckBox.Value
                                mz = app.Mz_groups{fz_counter, ia_counter, p_counter};
                                [C, exitflag] = fit_pacejka(alpha, mz, algorithm, max_iterations);
                                app.MzPacejkaCoeff(:, fz_counter, ia_counter, p_counter) = C;
                                app.MzExitFlags(fz_counter, ia_counter, p_counter) = exitflag;
                                
                                app.MzPacejkaVals(:, fz_counter, ia_counter, p_counter) = pacejka(app.MzPacejkaCoeff(:, fz_counter, ia_counter, p_counter), deg2rad(SA_vals));
                            end
                        end
                        
                        %if p_counter == 1 && ia_counter == 1 && fz_counter == 
                        
                    end % End of load loop (camber-specific)
                    
                    % Fit loads
                    for sa_counter = 1:n_SA
                        if app.SeparateSA
                            app.LoadPolyCoeff(:, sa_counter, 1, ia_counter, p_counter) = ...
                                polyfit(app.mean_loads(:, ia_counter, p_counter), ...
                                app.PacejkaVals(sa_counter, 1, :, ia_counter, p_counter), load_order);
                            app.LoadPolyCoeff(:, sa_counter, 2, ia_counter, p_counter) = ...
                                polyfit(app.mean_loads(:, ia_counter, p_counter), ...
                                app.PacejkaVals(sa_counter, 2, :, ia_counter, p_counter), load_order);
                            
                            % Populate the spaces in between the load data given with values on the polynomial
                            app.LatCoeffVals(sa_counter, 1, :, ia_counter, p_counter) = ...
                                polyval(app.LoadPolyCoeff(:, sa_counter, 1, ia_counter, p_counter), load_vals);
                            app.LatCoeffVals(sa_counter, 2, :, ia_counter, p_counter) = ...
                                polyval(app.LoadPolyCoeff(:, sa_counter, 2, ia_counter, p_counter), load_vals);
                        else
                            app.LoadPolyCoeff(:, sa_counter, ia_counter, p_counter) = ...
                                polyfit(app.mean_loads(:, ia_counter, p_counter), ...
                                app.PacejkaVals(sa_counter, :, ia_counter, p_counter), load_order);
                            % Populate the spaces in between the load data given with values on the polynomial
                            app.LatCoeffVals(sa_counter, :, ia_counter, p_counter) = ...
                                polyval(app.LoadPolyCoeff(:, sa_counter, ia_counter, p_counter), load_vals);
                            
                            if app.SaveFyandMzdataCheckBox.Value
                                app.MzLoadPolyCoeff(:, sa_counter, ia_counter, p_counter) = ...
                                    polyfit(app.mean_loads(:, ia_counter, p_counter), ...
                                    app.MzPacejkaVals(sa_counter, :, ia_counter, p_counter), load_order);
                                app.Mz_Vals(sa_counter, :, ia_counter, p_counter) = ...
                                    polyval(app.MzLoadPolyCoeff(:, sa_counter, ia_counter, p_counter), load_vals);
                            end
                        end
                    end
                    
                end % End of camber group (pressure-specific)
            end % End of pressure loop (run once)
        end
        
        function update_pacejka_axes(app)
            delete(app.PacejkaDataAxesHandle);
            delete(app.PacejkaFitAxesHandle);
            
            SAdir_index = get_SAdir_index_from_string(app.PacejkaSAdirDropDown.Value);
            
            fz_index = get_fz_index_from_string(app.PacejkaFZDropDown.Value);
            
            ia_index = get_ia_index_from_string(app.PacejkaIADropDown.Value);
            
            p_index = get_p_index_from_string(app.PacejkaPDropDown.Value);
            if app.SeparateSA
                x_data = app.SA_groups{SAdir_index, fz_index, ia_index, p_index};
                y_data = app.NFY_groups{SAdir_index, fz_index, ia_index, p_index};
            else
                x_data = app.SA_groups{fz_index, ia_index, p_index};
                y_data = app.NFY_groups{fz_index, ia_index, p_index};
            end
            
            app.PacejkaDataAxesHandle = scatter(app.PacejkaFitAxes, x_data, y_data, 5, 'blue');
            axis(app.PacejkaFitAxes, [-15 15 -3.2 3.2]);
            
            if app.DoFits
                x_limit = app.SABoundEditField.Value;
                delta_x = app.SAStepSizeEditField.Value;
                pacejka_x = -x_limit:delta_x:x_limit;
                if app.SeparateSA
                    pacejka_y = pacejka(app.PacejkaCoeff(:, SAdir_index, fz_index, ia_index, p_index), deg2rad(pacejka_x));
                else
                    pacejka_y = pacejka(app.PacejkaCoeff(:, fz_index, ia_index, p_index), deg2rad(pacejka_x));
                end
                app.PacejkaFitAxesHandle = plot(app.PacejkaFitAxes, pacejka_x, pacejka_y, 'red');
            end
            if app.SeparateSA
                app.FitnotcompletedLabel.Visible = app.SolverExitFlags(SAdir_index, fz_index, ia_index, p_index) == 0;
            else
                app.FitnotcompletedLabel.Visible = app.SolverExitFlags(fz_index, ia_index, p_index) == 0;
            end
        end
        
        function update_ui(~)
            % For some reason, this works
            pause(0.000001);
        end
        
        function update_tire_details(app, tire_id)
            round_num = app.getRoundNumber(tire_id);
            
            app.RoundDropDown.Value = num2str(round_num);
            
            manu = extractBetween(tire_id, '', ' ');
            
            app.ManufacturerDropDown.Value = manu{1};
        end
        
        function updateLoadSens(app)
            
            sa_index = find(app.SA_Vals == app.SASlider.Value);
            
            if app.SeparateSA
                SAdir_index = get_SAdir_index_from_string(app.FZSAdirDropDown.Value);
            else
                SAdir_index = 0;
            end
            
            ia_index = get_ia_index_from_string(app.FZIADropDown.Value);
            
            p_index = get_p_index_from_string(app.FZPDropDown.Value);
            
            plotLoadSens(app, sa_index, SAdir_index, ia_index, p_index);
        end
        
        function updateSAonLoadSens(app, newSA)
            
            sa_index = find(abs(app.SA_Vals - newSA) < 0.000001);
            
            if app.SeparateSA
                SAdir_index = get_SAdir_index_from_string(app.FZSAdirDropDown.Value);
            else
                SAdir_index = 0;
            end
            
            ia_index = get_ia_index_from_string(app.FZIADropDown.Value);
            
            p_index = get_p_index_from_string(app.FZPDropDown.Value);
            
            plotLoadSens(app, sa_index, SAdir_index, ia_index, p_index);
        end
        
        function plotLoadSens(app, sa_index, SAdir_index, ia_index, p_index)
            %delete(app.LoadSensAxesDataHandle);
            %delete(app.LoadSensAxesFitHandle);
            
            % Plot data points
            actual_load_vals = app.mean_loads(:, ia_index, p_index);
            if SAdir_index == 0
                actual_coeff = app.PacejkaVals(sa_index, :, ia_index, p_index);
            else
                actual_coeff = app.PacejkaVals(sa_index, SAdir_index, :, ia_index, p_index);
            end
            length(actual_load_vals)
            length(actual_coeff)
            app.LoadSensAxesDataHandle = scatter(app.FZAxes, actual_load_vals, actual_coeff, 'magenta');
            
            % Plot fit line
            if SAdir_index == 0
                yvals = app.LatCoeffVals(sa_index, :, ia_index, p_index);
            else
                yvals = app.LatCoeffVals(sa_index, SAdir_index, :, ia_index, p_index);
            end
            app.LoadSensAxesFitHandle = line(app.FZAxes, ...
                'XData', ...
                app.Load_Vals, ...
                'YData', ...
                reshape(yvals, size(app.Load_Vals)), ...
                'Color', 'cyan');
            drawnow
        end
        
        function round = getRoundNumber(app, tire_id)
            tire_info = app.TireInfoMap(tire_id);
            filename = tire_info.file_name;
            cell_val = extractBetween(filename, 2, 5);
            num = cell_val{1};
            switch num
                case '1051'
                    round = 1;
                case '1052'
                    round = 2;
                case '1175'
                    round = 3;
                case '1320'
                    round = 4;
                case '1464'
                    round = 5;
                case '1654'
                    round = 6;
                case '1706'
                    round = 7;
                case '1965'
                    round = 8;
            end
        end
        
        function addTestSectionAction(app, test_section_name, callback)
            app.TestSectionActions(test_section_name) = callback;
        end
        
        function SASweepsAction(app, relevant_data)
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PrimaryStatusLabel.Text = 'App started';
            update_ui(app);
            
            app.PacejkaFitAxes.Visible = false;
            app.FZAxes.Visible = false;
            app.FitnotcompletedLabel.Visible = false;
            
            % Empty map which wil be used to map each set of data to the tire name
            app.TireDataMap = containers.Map('KeyType', 'char', 'ValueType', 'any');
            
            % Load files in tire data directory
            cd 'C:\Users\Matthew\MATLAB\Projects\Tire Analyzer';
            folder_contents = what('TTC Tire Data\');
            % Only list .mat files
            mat_files = natsortfiles(folder_contents.mat);
            app.FileNames = mat_files;
            % Number of files
            app.n_files = length(mat_files);
            
            % Load each tire's name into the array
            % At some point, we can parse the tire info from the string
            app.PrimaryStatusLabel.Text = 'Loading data from files into workspace...';
            update_ui(app);
            app.TireNamesRaw = cell(app.n_files);
            tire_names = cell(1);
            found_index = 0;
            for i = 1:app.n_files
                app.SecondaryStatusLabel.Text = "Loading file " + i + "/" + app.n_files;
                %update_ui(app);
                %disp("Loading file " + i + "/" + app.n_files)
                
                %disp('--------- New file --------')
                
                file_path = strcat('TTC Tire Data/', mat_files{i});
                
                % Load the tire info and data
                tire_data = load(file_path);
                tire_id = tire_data.tireid;
                
                %disp(strcat('i: ', num2str(i)))
                %disp(strcat('Tire id: ', tire_id))
                
                app.TireDataRaw{i} = tire_data;
                
                app.TireInfoRaw{i} = struct('file_name', mat_files{i}, 'tire_id', tire_data.tireid);
                
%                 if app.TireData.isKey(tire_id) % If it's another file for an existing tire
%                     %disp('Another file for this tire')
%                     
%                     if isempty(app.TireDataMap(tire_id))
%                         %disp(strcat('Setting data at index: ', num2str(i)))
%                         %disp(strcat('found_index is: ', num2str(found_index)))
%                         tire_data.filename = mat_files{i};
%                         app.TireDataMap(tire_id) = tire_data;
%                     end
%                 else % If this is the first file for this tire
%                     if isempty(app.TireData)
%                         tire_names{1} = tire_id;
%                     else
%                         tire_names{length(tire_names) + 1} = tire_id;
%                     end
%                     %disp(class(tire_id))
%                     %tire_id
%                     found_index = found_index + 1;
%                     app.FileStartIndices(found_index) = i;
%                     %disp('First file for this tire')
%                     app.TireDataMap(tire_id) = '';
%                end
            end
            app.PrimaryStatusLabel.Text = 'Done loading files';
            app.SecondaryStatusLabel.Text = '';
            
            % Concatenate the data from different files but same tire
            app.PrimaryStatusLabel.Text = 'Concantenating data...';
%             field_names = fieldnames(app.TireDataRaw{1}); % Get the field names of the data from one of the files
%             for i = 1:numel(field_names)
%                 app.SecondaryStatusLabel.Text = "Concatenating field: " + field_names{i};
%                 update_ui(app);
%                 for j = 1:length(app.TireDataRaw)
%                     if isfield(app.TireData, field_names{i}) ...
%                             && ~strcmp(field_names{i}, 'channel') ...
%                             && ~strcmp(field_names{i}, 'source') ...
%                             && ~strcmp(field_names{i}, 'testid') ...
%                             && ~strcmp(field_names{i}, 'tireid')
%                     app.TireData.(field_names{i}) = [app.TireData.(field_names{i}); app.TireData{j}.(field_names{i})];
%                     else
%                         app.TireDataMap(1).(field_names{i}) = app.TireData{j}.(field_names{i});
%                     end
%                 end
%             end
            for i = 1:length(app.TireDataRaw) % Iterate over tire data entries (one for each file)
                tire_name = app.TireDataRaw{i}.tireid;
                
                if contains(tire_name, '--')
                    tire_name = extractBetween(tire_name, '', ' --');
                    tire_name = tire_name{1};
                end
                
                field_names = fieldnames(app.TireDataRaw{i});
                
                is_new = ~ismember(tire_name, app.TireDataMap.keys);
                
                if is_new
                    data_struct = struct();
                else
                    original_data = app.TireDataMap(tire_name);
                end
                
                new_data = app.TireDataRaw{i};
                
                for j = 1:numel(field_names) % Iterate over field names
                    field_name_cell = field_names(j);
                    field_name = field_name_cell{1};
                    if ~strcmp(field_names{j}, 'channel') ...
                            && ~strcmp(field_names{j}, 'source') ...
                            && ~strcmp(field_names{j}, 'testid') ...
                            && ~strcmp(field_names{j}, 'tireid') % Don't concatenate these fields
                    
                        if is_new % If this is the first file for this tire
                            data_struct.(field_name) = new_data.(field_name);
                        else % If this is a tire that already came up
                            original_data.(field_name) = ...
                                [original_data.(field_name); new_data.(field_name)];
                        end
                    end
                end
                % Less than optimal solution since this has to be checked
                % again... but it's preferred over iterating over field
                % names first and then tires
                if is_new
                    app.TireDataMap(tire_name) = data_struct;
                    app.TireInfoMap(tire_name) = app.TireInfoRaw{i};
                    app.TireNamesSorted{end + 1} = tire_name;
                else
                    app.TireDataMap(tire_name) = original_data;
                end
            end
            
            app.n_tires = size(app.TireDataMap, 1);
            app.TireNames = app.TireDataMap.keys; % Not sure if necessary
            
            app.TireDropDown.Items = app.TireNamesSorted;
            
            update_tire_details(app, app.TireDropDown.Value);
            
            disableDefaultInteractivity(app.PacejkaFitAxes);
            disableDefaultInteractivity(app.FZAxes);
            
            app.PacejkaFitAxes.Toolbar.Visible = false;
            app.FZAxes.Toolbar.Visible = false;
        end

        % Button pushed function: RunButton
        function runButtonPushed(app, event)
            app.PrimaryStatusLabel.Text = 'Started running...';
            update_ui(app);
            % Disable all relevant ui elements while loading
            %app.RunOptionsPanel.Enable = 'off';
            %app.PlotsPanel.Enable = 'off';
            
            tire_name = app.TireDropDown.Value;
            app.Round = app.getRoundNumber(tire_name);
            
            app.UsedTireData = app.TireDataMap(tire_name);
            %length(app.UsedTireData.ET)
            if app.SeparateSA
                enable_str = 'on';
            else
                enable_str = 'off';
            end
            app.PacejkaSAdirDropDown.Enable = enable_str;
            app.FZSAdirDropDown.Enable = enable_str;
            
            app.RunOptions.SaveData = app.SaveFyandMzdataCheckBox.Value;
            app.RunOptions.UseDiffScalingFactors = app.UsedifferentXandYscalingfactorsCheckBox.Value;
            app.RunOptions.XScalingFactor = app.XscalingfactorEditField.Value;
            if app.UsedifferentXandYscalingfactorsCheckBox.Value
                app.RunOptions.YScalingFactor = app.YscalingfactorEditField.Value;
            else
                app.RunOptions.YScalingFactor = app.RunOptions.XScalingFactor;
            end
            app.RunOptions.CreateFits = app.CreatefitsCheckBox.Value;
            app.RunOptions.SeparateSADir = app.SeparateSACheckBox.Value;
            app.RunOptions.SABound = app.SABoundEditField.Value;
            app.RunOptions.SAStepSize = app.SAStepSizeEditField.Value;
            app.RunOptions.SASampleVals = -app.RunOptions.SABound:app.RunOptions.SAStepSize:app.RunOptions.SABound;
            app.RunOptions.PacejkaMaxIterations = app.PacejkafitmaxiterationsEditField.Value;
            app.RunOptions.PacejkaAlgorithm = app.PacejkaalgorithmDropDown.Value;
            app.RunOptions.LoadStepSize = app.LoadStepSizeEditField.Value;
            app.RunOptions.LoadUpperBound = app.LoadUBEditField.Value;
            app.RunOptions.LoadSampleVals = -app.RunOptions.LoadUpperBound:app.RunOptions.LoadStepSize:0;
            app.RunOptions.LoadSensOrder = app.LoadSensitivityOrderSpinner;
            
            % Testing test format specification
            testFormats = TestFormatSpec.getTestFormats();
            n_rounds = length(testFormats);
            for i = 1:n_rounds
                n_test_sections = length(testFormats(i).Tests);
                start_index = 0;
                if testFormats(i).RoundNum == app.Round
                    disp(['Round: ' num2str(testFormats(i).RoundNum)])
                    n_sections_listed = 0;
                    for j = 1:n_test_sections
                        n_sections_listed = n_sections_listed + 1;
                        test = testFormats(i).Tests(j);
                        ub = test.getUpperBound(app.UsedTireData, start_index, []);
                        % Don't care about return struct bc the top level tests access the app directly
                        test.doStuffWithData(app, app.UsedTireData, start_index + 1, ub, [], app.RunOptions);
                        start_index = ub;
                        app.TestSectionUpperBounds{j} = ub;
                        %disp(['Test: ' test.Name ', UB: ' num2str(ub)])
                        sections_list(n_sections_listed) = test;
                        if isa(test, 'TestSectionNested')
                            nested_tests = test.NestedTests;
                            for k = 1:length(nested_tests)
                                n_sections_listed = n_sections_listed + 1;
                                sections_list(n_sections_listed) = nested_tests(i);
                            end
                        end
                    end
                    break;
                end
            end
            
%             for i = 1:n_sections_listed
%                 test_name = sections_list(i).Name;
%                 if ismember(test_name, app.TestSectionActions.keys)
%                     action = app.TestSectionActions(test_name);
%                     action.whenRun()
%                 end
%             end
            
            if isempty(app.UsedTireData)
                error('Matt error: tire not found');
            end
            %s = app.UsedTireData;
            %disp(strcat('UsedTireData variable: ', s))
            
            %app.StartIndex = app.find_start_index();
            
            app.StartIndex = 1;
            
            x_scaling_factor = app.XscalingfactorEditField.Value;
            if app.UsedifferentXandYscalingfactorsCheckBox.Value
                y_scaling_factor = app.YscalingfactorEditField.Value;
            else
                y_scaling_factor = x_scaling_factor;
            end
            
            separate_data(app, x_scaling_factor, y_scaling_factor);
            
            SA_bound = app.SABoundEditField.Value;
            delta_SA = app.SAStepSizeEditField.Value;
            app.SA_Vals = -SA_bound:delta_SA:SA_bound;
            
            load_ub = app.LoadUBEditField.Value;
            delta_load = app.LoadStepSizeEditField.Value;
            app.Load_Vals = 0:delta_load:load_ub;
            
            app.DoFits = app.CreatefitsCheckBox.Value;
            
            fit_data(app, ...
                app.PacejkaalgorithmDropDown.Value, ...
                app.PacejkafitmaxiterationsEditField.Value, ...
                [-app.SABoundEditField.Value app.SABoundEditField.Value], ...
                app.SAStepSizeEditField.Value, ...
                [app.LoadBounds], ...
                app.LoadStepSizeEditField.Value, ...
                app.LoadSensitivityOrderSpinner.Value);
            
            n_success = sum(ismember(app.SolverExitFlags, [1 2 3]), 'all');
            
            app.PrimaryStatusLabel.Text = 'Plotting Pacejka fits...';
            app.SecondaryStatusLabel.Text = '';
            app.TertiaryStatusLabel.Text = '';
            update_ui(app);
            
            hold(app.PacejkaFitAxes, "on");
            plot(app.PacejkaFitAxes, [0 0], y_scaling_factor*[-3.2 3.2], 'black');
            plot(app.PacejkaFitAxes, x_scaling_factor*[-15 15], [0 0], 'black');
            update_pacejka_axes(app);
            
            app.PacejkaClickRunLabel.Visible = false;
            app.LoadClickRunLabel.Visible = false;
            update_ui(app);
            app.PacejkaFitAxes.Visible = true;
            app.FZAxes.Visible = true;
            
            app.PrimaryStatusLabel.Text = 'Plotting load sensitivity...';
            update_ui(app);
            updateLoadSens(app);
            
            app.PrimaryStatusLabel.Text = ['Finished with ' num2str(n_success) '/' num2str(numel(app.SolverExitFlags)) ' successful Pacejka fits'];
            % Re-enable all relevant ui elements
            app.RunOptionsPanel.Enable = 'on';
            app.PlotsPanel.Enable = 'on';
            update_ui(app);
            
            if app.SaveFyandMzdataCheckBox.Value
                sa_x = deg2rad(app.SA_Vals);
                load_y = -app.Load_Vals;
                lat_coeff = app.LatCoeffVals(:, :, 1, 1);
                aligning_torque = app.Mz_Vals(:, :, 1, 1);
                save('lateral_data_lut.mat', 'sa_x', 'load_y', 'lat_coeff', 'aligning_torque');
            end
        end

        % Value changed function: PacejkaSAdirDropDown
        function pacejkaSAdirDropDownValueChanged(app, event)
            update_pacejka_axes(app);
        end

        % Value changed function: PacejkaFZDropDown
        function pacejkaFZDropDownValueChanged(app, event)
            update_pacejka_axes(app);
        end

        % Value changed function: PacejkaIADropDown
        function pacejkaIADropDownValueChanged(app, event)
            update_pacejka_axes(app);
        end

        % Value changed function: PacejkaPDropDown
        function PacejkaPDownDownValueChanged(app, event)
            update_pacejka_axes(app);
        end

        % Selection changed function: ButtonGroup
        function tireSelectionMethodChanged(app, event)
            useDropDown = app.ButtonGroup.SelectedObject == app.SelectfromdropdownButton;
            app.TireDropDown.Enable = useDropDown;
            app.RoundDropDown.Enable = ~useDropDown;
            app.ManufacturerDropDown.Enable = ~useDropDown;
            app.OuterdiameterDropDown.Enable = ~useDropDown;
            app.TirewidthDropDown.Enable = ~useDropDown;
            app.RimouterdiameterDropDown.Enable = ~useDropDown;
        end

        % Value changed function: 
        % UsedifferentXandYscalingfactorsCheckBox
        function useDifferentScalingFactorsChanged(app, event)
            value = app.UsedifferentXandYscalingfactorsCheckBox.Value;
            app.YscalingfactorEditField.Visible = value;
            app.ForcecoefficientYscalingfactorEditFieldLabel.Visible = value;
            if (~value)
                app.ScalingfactorLabel.Text = 'Scaling factor:';
            else
                app.ScalingfactorLabel.Text = 'Slip (X) scaling factor:';
            end
        end

        % Value changed function: CreatefitsCheckBox
        function createFitsChanged(app, event)
            if app.CreatefitsCheckBox.Value
                app.FitOptionsPanel.Enable = 'on';
            else
                app.FitOptionsPanel.Enable = 'off';
            end
        end

        % Value changed function: TireDropDown
        function tireDropDownChanged(app, event)
            value = app.TireDropDown.Value;
            update_tire_details(app, value);
        end

        % Value changed function: FZPDropDown
        function LoadSensPDropDownChanged(app, event)
            updateLoadSens(app);
        end

        % Value changed function: FZIADropDown
        function LoadSensIADropDownChanged(app, event)
            updateLoadSens(app);
        end

        % Value changed function: FZSAdirDropDown
        function LoadSensSAdirChanged(app, event)
            updateLoadSens(app);
        end

        % Value changed function: SASlider
        function LoadSensSASliderChanged(app, event)
            disp('Function called')
            newValue = event.Value;
            roundedValue = round(newValue, 1);
            if roundedValue ~= app.SASlider.Value
                disp('Changing slider')
                disp('Rounded value:')
                disp(roundedValue)
                updateSAonLoadSens(app, roundedValue);
                disp('Finished updating plot')
            end
            app.SASlider.Value = roundedValue;
        end

        % Value changed function: SeparateSACheckBox
        function separateSAValueChanged(app, event)
            value = app.SeparateSACheckBox.Value;
            app.SaveFyandMzdataCheckBox.Value = false;
            app.SaveFyandMzdataCheckBox.Enable = ~value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TireAnalyzerUIFigure = uifigure('Visible', 'off');
            app.TireAnalyzerUIFigure.Name = 'Tire Analyzer';
            app.TireAnalyzerUIFigure.Icon = 'RFRlogo.png';
            app.PlotsPanel = uipanel(app.TireAnalyzerUIFigure);
            app.GridLayout = uigridlayout(app.PlotsPanel);
            app.PacejkaFitsPanel = uipanel(app.GridLayout);
            app.PacejkaFitsPanel.Title = 'Pacejka fits';
            app.PpsiLabel = uilabel(app.PacejkaFitsPanel);
            app.PpsiLabel.Text = 'P (psi):';
            app.PacejkaPDropDown = uidropdown(app.PacejkaFitsPanel);
            app.PacejkaPDropDown.Items = {'10', '12', '14'};
            app.PacejkaPDropDown.ValueChangedFcn = createCallbackFcn(app, @PacejkaPDownDownValueChanged, true);
            app.PacejkaPDropDown.Value = '10';
            app.IAdegLabel = uilabel(app.PacejkaFitsPanel);
            app.IAdegLabel.Text = 'IA (deg):';
            app.PacejkaIADropDown = uidropdown(app.PacejkaFitsPanel);
            app.PacejkaIADropDown.Items = {'0', '2', '4'};
            app.PacejkaIADropDown.ValueChangedFcn = createCallbackFcn(app, @pacejkaIADropDownValueChanged, true);
            app.PacejkaIADropDown.Value = '0';
            app.FZNLabel = uilabel(app.PacejkaFitsPanel);
            app.FZNLabel.Text = 'FZ (N):';
            app.PacejkaFZDropDown = uidropdown(app.PacejkaFitsPanel);
            app.PacejkaFZDropDown.Items = {'200', '400', '600', '800', '1000'};
            app.PacejkaFZDropDown.ValueChangedFcn = createCallbackFcn(app, @pacejkaFZDropDownValueChanged, true);
            app.PacejkaFZDropDown.Value = '200';
            app.SAdirDropDownLabel = uilabel(app.PacejkaFitsPanel);
            app.SAdirDropDownLabel.Text = 'SA dir:';
            app.PacejkaSAdirDropDown = uidropdown(app.PacejkaFitsPanel);
            app.PacejkaSAdirDropDown.Items = {'+', '-'};
            app.PacejkaSAdirDropDown.ValueChangedFcn = createCallbackFcn(app, @pacejkaSAdirDropDownValueChanged, true);
            app.PacejkaSAdirDropDown.Value = '+';
            app.PacejkaClickRunLabel = uilabel(app.PacejkaFitsPanel);
            app.PacejkaClickRunLabel.Text = 'Click Run to view graphs';
            app.FitnotcompletedLabel = uilabel(app.PacejkaFitsPanel);
            app.FitnotcompletedLabel.Text = 'Fit not completed';
            app.PacejkaFitAxes = uiaxes(app.PacejkaFitsPanel);
            title(app.PacejkaFitAxes, 'Pacejka fit')
            xlabel(app.PacejkaFitAxes, 'Slip angle (deg)')
            ylabel(app.PacejkaFitAxes, 'Lateral coefficient')
            app.PacejkaFitAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.CamberSensitivityPanel = uipanel(app.GridLayout);
            app.CamberSensitivityPanel.Title = 'Camber sensitivity';
            app.NotimplementedyetLabel = uilabel(app.CamberSensitivityPanel);
            app.NotimplementedyetLabel.Text = 'Not implemented yet';
            app.PressureSensitivityPanel = uipanel(app.GridLayout);
            app.PressureSensitivityPanel.Title = 'Pressure sensitivity';
            app.NotimplementedyetLabel_2 = uilabel(app.PressureSensitivityPanel);
            app.NotimplementedyetLabel_2.Text = 'Not implemented yet';
            app.LoadSensitivityPanel = uipanel(app.GridLayout);
            app.LoadSensitivityPanel.Title = 'Load sensitivity';
            app.PpsiDropDown_2Label = uilabel(app.LoadSensitivityPanel);
            app.PpsiDropDown_2Label.Text = 'P (psi):';
            app.IAdegDropDown_2Label = uilabel(app.LoadSensitivityPanel);
            app.IAdegDropDown_2Label.Text = 'IA (deg):';
            app.SAdirDropDownLabel_2 = uilabel(app.LoadSensitivityPanel);
            app.SAdirDropDownLabel_2.Text = 'SA dir:';
            app.SASliderPanel = uipanel(app.LoadSensitivityPanel);
            app.UBdegLabel = uilabel(app.SASliderPanel);
            app.UBdegLabel.Text = '15 deg';
            app.SASlider = uislider(app.SASliderPanel);
            app.SASlider.Limits = [-15 15];
            app.SASlider.MajorTicks = [-15 0 15];
            app.SASlider.MajorTickLabels = {''};
            app.SASlider.ValueChangedFcn = createCallbackFcn(app, @LoadSensSASliderChanged, true);
            app.LBdegLabel = uilabel(app.SASliderPanel);
            app.LBdegLabel.Text = '-15 deg';
            app.FZSAdirDropDown = uidropdown(app.LoadSensitivityPanel);
            app.FZSAdirDropDown.Items = {'+', '-'};
            app.FZSAdirDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadSensSAdirChanged, true);
            app.FZSAdirDropDown.Value = '+';
            app.FixDropDownLabel = uilabel(app.LoadSensitivityPanel);
            app.FixDropDownLabel.Enable = 'off';
            app.FixDropDownLabel.Text = 'Fix:';
            app.FixDropDown = uidropdown(app.LoadSensitivityPanel);
            app.FixDropDown.Items = {'IA', 'P'};
            app.FixDropDown.Enable = 'off';
            app.FixDropDown.Value = 'IA';
            app.FZPDropDown = uidropdown(app.LoadSensitivityPanel);
            app.FZPDropDown.Items = {'10', '12', '14'};
            app.FZPDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadSensPDropDownChanged, true);
            app.FZPDropDown.Value = '10';
            app.FZIADropDown = uidropdown(app.LoadSensitivityPanel);
            app.FZIADropDown.Items = {'0', '2', '4'};
            app.FZIADropDown.ValueChangedFcn = createCallbackFcn(app, @LoadSensIADropDownChanged, true);
            app.FZIADropDown.Value = '0';
            app.LoadClickRunLabel = uilabel(app.LoadSensitivityPanel);
            app.LoadClickRunLabel.Text = 'Click Run to view graphs';
            app.FZAxes = uiaxes(app.LoadSensitivityPanel);
            title(app.FZAxes, 'Load sensitivity and fit at SA=Xdeg')
            xlabel(app.FZAxes, 'Load (N)')
            ylabel(app.FZAxes, 'Lateral coefficient')
            app.FZAxes.YLim = [-3.2 3.2];
            app.FZAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.StatusPanel = uipanel(app.TireAnalyzerUIFigure);
            app.StatusPanel.Title = 'Status';
            app.PrimaryStatusLabel = uilabel(app.StatusPanel);
            app.PrimaryStatusLabel.Text = '';
            app.SecondaryStatusLabel = uilabel(app.StatusPanel);
            app.SecondaryStatusLabel.Text = '';
            app.TertiaryStatusLabel = uilabel(app.StatusPanel);
            app.TertiaryStatusLabel.Text = '';
            app.TitlePanel = uipanel(app.TireAnalyzerUIFigure);
            app.TireAnalyzerLabel = uilabel(app.TitlePanel);
            app.TireAnalyzerLabel.Text = 'Tire Analyzer';
            app.ByMatthewDrummondStoylesLabel = uilabel(app.TitlePanel);
            app.ByMatthewDrummondStoylesLabel.Text = 'By Matthew Drummond-Stoyles';
            app.RFRImage = uiimage(app.TitlePanel);
            app.RFRImage.ImageSource = 'RFRlogo.png';
            app.RunOptionsPanel = uipanel(app.TireAnalyzerUIFigure);
            app.TireDropDownLabel = uilabel(app.RunOptionsPanel);
            app.TireDropDownLabel.Text = 'Tire';
            app.TireDropDown = uidropdown(app.RunOptionsPanel);
            app.TireDropDown.Items = {'-'};
            app.TireDropDown.ValueChangedFcn = createCallbackFcn(app, @tireDropDownChanged, true);
            app.TireDropDown.Value = '-';
            app.RunButton = uibutton(app.RunOptionsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.CreatefitsCheckBox = uicheckbox(app.RunOptionsPanel);
            app.CreatefitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @createFitsChanged, true);
            app.CreatefitsCheckBox.Text = 'Create fits';
            app.CreatefitsCheckBox.Value = true;
            app.FitOptionsPanel = uipanel(app.RunOptionsPanel);
            app.FitOptionsPanel.Title = 'Fit Options';
            app.LoadsensitivityorderLabel = uilabel(app.FitOptionsPanel);
            app.LoadsensitivityorderLabel.Text = 'Load sensitivity order:';
            app.LoadSensitivityOrderSpinner = uispinner(app.FitOptionsPanel);
            app.LoadSensitivityOrderSpinner.Limits = [1 4];
            app.LoadSensitivityOrderSpinner.Tooltip = {'Polynomial order that will be fit to the load sensitivity data.'};
            app.LoadSensitivityOrderSpinner.Value = 2;
            app.SlipanglebounddegLabel = uilabel(app.FitOptionsPanel);
            app.SlipanglebounddegLabel.Text = 'Slip angle bound (deg):';
            app.SABoundEditField = uieditfield(app.FitOptionsPanel, 'numeric');
            app.SABoundEditField.Value = 15;
            app.SlipanglestepsizedegEditFieldLabel = uilabel(app.FitOptionsPanel);
            app.SlipanglestepsizedegEditFieldLabel.Text = 'Slip angle step size (deg):';
            app.SAStepSizeEditField = uieditfield(app.FitOptionsPanel, 'numeric');
            app.SAStepSizeEditField.Value = 0.1;
            app.LoadstepsizeNEditFieldLabel = uilabel(app.FitOptionsPanel);
            app.LoadstepsizeNEditFieldLabel.Text = 'Load step size (N):';
            app.LoadStepSizeEditField = uieditfield(app.FitOptionsPanel, 'numeric');
            app.LoadStepSizeEditField.Value = 20;
            app.LoadupperboundNEditFieldLabel = uilabel(app.FitOptionsPanel);
            app.LoadupperboundNEditFieldLabel.Text = 'Load upper bound (N):';
            app.LoadUBEditField = uieditfield(app.FitOptionsPanel, 'numeric');
            app.LoadUBEditField.Value = 1200;
            app.PacejkafitmaxiterationsEditFieldLabel = uilabel(app.FitOptionsPanel);
            app.PacejkafitmaxiterationsEditFieldLabel.Text = 'Pacejka fit max iterations:';
            app.PacejkafitmaxiterationsEditField = uieditfield(app.FitOptionsPanel, 'numeric');
            app.PacejkafitmaxiterationsEditField.Value = 3000;
            app.PacejkaalgorithmDropDownLabel = uilabel(app.FitOptionsPanel);
            app.PacejkaalgorithmDropDownLabel.Text = 'Pacejka algorithm:';
            app.PacejkaalgorithmDropDown = uidropdown(app.FitOptionsPanel);
            app.PacejkaalgorithmDropDown.Items = {'trust-region-reflective', 'levenberg-marquardt'};
            app.PacejkaalgorithmDropDown.Value = 'levenberg-marquardt';
            app.SeparateSACheckBox = uicheckbox(app.FitOptionsPanel);
            app.SeparateSACheckBox.ValueChangedFcn = createCallbackFcn(app, @separateSAValueChanged, true);
            app.SeparateSACheckBox.Text = 'Separate increasing/decreasing SA';
            app.ButtonGroup = uibuttongroup(app.RunOptionsPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @tireSelectionMethodChanged, true);
            app.SelectfromdropdownButton = uiradiobutton(app.ButtonGroup);
            app.SelectfromdropdownButton.Text = 'Select from dropdown';
            app.SelectfromdropdownButton.Value = true;
            app.SelectindividualparametersButton = uiradiobutton(app.ButtonGroup);
            app.SelectindividualparametersButton.Enable = 'off';
            app.SelectindividualparametersButton.Text = 'Select individual parameters';
            app.RoundDropDownLabel = uilabel(app.RunOptionsPanel);
            app.RoundDropDownLabel.Enable = 'off';
            app.RoundDropDownLabel.Text = 'Round:';
            app.RoundDropDown = uidropdown(app.RunOptionsPanel);
            app.RoundDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.RoundDropDown.Enable = 'off';
            app.RoundDropDown.Value = '1';
            app.ManufacturerDropDownLabel = uilabel(app.RunOptionsPanel);
            app.ManufacturerDropDownLabel.Enable = 'off';
            app.ManufacturerDropDownLabel.Text = 'Manufacturer:';
            app.ManufacturerDropDown = uidropdown(app.RunOptionsPanel);
            app.ManufacturerDropDown.Items = {'Avon', 'Continental', 'Dunlop', 'Goodyear', 'Hoosier', 'Michelin'};
            app.ManufacturerDropDown.Enable = 'off';
            app.ManufacturerDropDown.Value = 'Avon';
            app.OuterdiameterDropDownLabel = uilabel(app.RunOptionsPanel);
            app.OuterdiameterDropDownLabel.Enable = 'off';
            app.OuterdiameterDropDownLabel.Text = 'Outer diameter:';
            app.OuterdiameterDropDown = uidropdown(app.RunOptionsPanel);
            app.OuterdiameterDropDown.Enable = 'off';
            app.TirewidthDropDownLabel = uilabel(app.RunOptionsPanel);
            app.TirewidthDropDownLabel.Enable = 'off';
            app.TirewidthDropDownLabel.Text = 'Tire width:';
            app.TirewidthDropDown = uidropdown(app.RunOptionsPanel);
            app.TirewidthDropDown.Enable = 'off';
            app.RimouterdiameterDropDownLabel = uilabel(app.RunOptionsPanel);
            app.RimouterdiameterDropDownLabel.Enable = 'off';
            app.RimouterdiameterDropDownLabel.Text = 'Rim outer diameter:';
            app.RimouterdiameterDropDown = uidropdown(app.RunOptionsPanel);
            app.RimouterdiameterDropDown.Enable = 'off';
            app.ScalingfactorsPanel = uipanel(app.RunOptionsPanel);
            app.ScalingfactorsPanel.Title = 'Scaling factors';
            app.UsedifferentXandYscalingfactorsCheckBox = uicheckbox(app.ScalingfactorsPanel);
            app.UsedifferentXandYscalingfactorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @useDifferentScalingFactorsChanged, true);
            app.UsedifferentXandYscalingfactorsCheckBox.Text = 'Use different X and Y scaling factors';
            app.ScalingfactorLabel = uilabel(app.ScalingfactorsPanel);
            app.ScalingfactorLabel.Text = 'Scaling factor:';
            app.XscalingfactorEditField = uieditfield(app.ScalingfactorsPanel, 'numeric');
            app.XscalingfactorEditField.Limits = [0 Inf];
            app.XscalingfactorEditField.Value = 1;
            app.ForcecoefficientYscalingfactorEditFieldLabel = uilabel(app.ScalingfactorsPanel);
            app.ForcecoefficientYscalingfactorEditFieldLabel.Visible = 'off';
            app.ForcecoefficientYscalingfactorEditFieldLabel.Text = 'Force coefficient (Y) scaling factor:';
            app.YscalingfactorEditField = uieditfield(app.ScalingfactorsPanel, 'numeric');
            app.YscalingfactorEditField.Limits = [0 Inf];
            app.YscalingfactorEditField.Visible = 'off';
            app.YscalingfactorEditField.Value = 1;
            app.CompoundLabel = uilabel(app.RunOptionsPanel);
            app.CompoundLabel.Enable = 'off';
            app.CompoundLabel.Text = 'Compound:';
            app.CompoundDropDown = uidropdown(app.RunOptionsPanel);
            app.CompoundDropDown.Enable = 'off';
            app.RimwidthDropDownLabel = uilabel(app.RunOptionsPanel);
            app.RimwidthDropDownLabel.Enable = 'off';
            app.RimwidthDropDownLabel.Text = 'Rim width:';
            app.RimwidthDropDown = uidropdown(app.RunOptionsPanel);
            app.RimwidthDropDown.Enable = 'off';
            app.RoundCheckBox = uicheckbox(app.RunOptionsPanel);
            app.RoundCheckBox.Enable = 'off';
            app.RoundCheckBox.Text = '';
            app.ManufacturerCheckBox = uicheckbox(app.RunOptionsPanel);
            app.ManufacturerCheckBox.Enable = 'off';
            app.ManufacturerCheckBox.Text = '';
            app.ODCheckbox = uicheckbox(app.RunOptionsPanel);
            app.ODCheckbox.Enable = 'off';
            app.ODCheckbox.Text = '';
            app.TireWidthCheckBox = uicheckbox(app.RunOptionsPanel);
            app.TireWidthCheckBox.Enable = 'off';
            app.TireWidthCheckBox.Text = '';
            app.RimODCheckBox = uicheckbox(app.RunOptionsPanel);
            app.RimODCheckBox.Enable = 'off';
            app.RimODCheckBox.Text = '';
            app.CompoundCheckBox = uicheckbox(app.RunOptionsPanel);
            app.CompoundCheckBox.Enable = 'off';
            app.CompoundCheckBox.Text = '';
            app.RimWidthCheckBox = uicheckbox(app.RunOptionsPanel);
            app.RimWidthCheckBox.Enable = 'off';
            app.RimWidthCheckBox.Text = '';
            app.SaveFyandMzdataCheckBox = uicheckbox(app.RunOptionsPanel);
            app.SaveFyandMzdataCheckBox.Text = 'Save Fy and Mz data';
            % Show the figure after all components are created
            app.TireAnalyzerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TireAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TireAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TireAnalyzerUIFigure)
        end
    end
end