classdef TireAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TireAnalyzerUIFigure            matlab.ui.Figure
        FitOptionsPanel                 matlab.ui.container.Panel
        GridLayout22                    matlab.ui.container.GridLayout
        GridLayout20                    matlab.ui.container.GridLayout
        PacejkaSimilarityMethodPanel    matlab.ui.container.Panel
        GridLayout26                    matlab.ui.container.GridLayout
        Panel_48                        matlab.ui.container.Panel
        GridLayout27                    matlab.ui.container.GridLayout
        CamberShiftCheckbox             matlab.ui.control.CheckBox
        CoFAdjustmentCheckbox           matlab.ui.control.CheckBox
        StiffnessAdjustmentCheckbox     matlab.ui.control.CheckBox
        LoadAdjustmentCheckbox          matlab.ui.control.CheckBox
        OldPacejkaFitsPanel             matlab.ui.container.Panel
        GridLayout24                    matlab.ui.container.GridLayout
        Panel_41                        matlab.ui.container.Panel
        Panel_40                        matlab.ui.container.Panel
        SaveFyandMzdatainLUTCheckBox    matlab.ui.control.CheckBox
        Panel_39                        matlab.ui.container.Panel
        FitmaxiterationsEditField       matlab.ui.control.NumericEditField
        FitmaxiterationsEditFieldLabel  matlab.ui.control.Label
        Panel_38                        matlab.ui.container.Panel
        FitalgorithmDropdown            matlab.ui.control.DropDown
        FitalgorithmLabel               matlab.ui.control.Label
        Panel_37                        matlab.ui.container.Panel
        SABoundEditField                matlab.ui.control.NumericEditField
        SlipanglebounddegLabel          matlab.ui.control.Label
        Panel_36                        matlab.ui.container.Panel
        SAStepSizeEditField             matlab.ui.control.NumericEditField
        SlipanglestepsizedegEditFieldLabel  matlab.ui.control.Label
        LoadSensitivityPolynomialFitsPanel  matlab.ui.container.Panel
        GridLayout23                    matlab.ui.container.GridLayout
        Panel_35                        matlab.ui.container.Panel
        Panel_34                        matlab.ui.container.Panel
        LoadStepSizeEditField           matlab.ui.control.NumericEditField
        LoadstepsizeNEditFieldLabel     matlab.ui.control.Label
        Panel_33                        matlab.ui.container.Panel
        LoadUBEditField                 matlab.ui.control.NumericEditField
        LoadupperboundNEditFieldLabel   matlab.ui.control.Label
        Panel_32                        matlab.ui.container.Panel
        LoadSensitivityOrderSpinner     matlab.ui.control.Spinner
        LoadsensitivityorderLabel       matlab.ui.control.Label
        TotalGridLayout                 matlab.ui.container.GridLayout
        TopGridLayout                   matlab.ui.container.GridLayout
        GridLayout19                    matlab.ui.container.GridLayout
        ByMatthewDrummondStoylesandDanielVenturaLabel  matlab.ui.control.Label
        RFRImage                        matlab.ui.control.Image
        GridLayout18                    matlab.ui.container.GridLayout
        TIREANALYZERLabel               matlab.ui.control.Label
        Panel_22                        matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        SourceCodeHyperlink             matlab.ui.control.Hyperlink
        SeesourcecodeLabel              matlab.ui.control.Label
        BottomGridLayout                matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        PacejkaFitsTab                  matlab.ui.container.Tab
        GridLayout21                    matlab.ui.container.GridLayout
        PacejkaFitNotCompletedLabel     matlab.ui.control.Label
        GridLayout4                     matlab.ui.container.GridLayout
        Panel_9                         matlab.ui.container.Panel
        PacejkaSAdirDropdownLabel       matlab.ui.control.Label
        PacejkaSAdirDropdown            matlab.ui.control.DropDown
        Panel_27                        matlab.ui.container.Panel
        PacejkaVaryDropdown             matlab.ui.control.DropDown
        PacejkaVaryDropdownLabel        matlab.ui.control.Label
        Panel_26                        matlab.ui.container.Panel
        PacejkaIADropdownLabel          matlab.ui.control.Label
        PacejkaIADropdown               matlab.ui.control.DropDown
        Panel_25                        matlab.ui.container.Panel
        PacejkaFZDropdownLabel          matlab.ui.control.Label
        PacejkaFZDropdown               matlab.ui.control.DropDown
        Panel_24                        matlab.ui.container.Panel
        PacejkaPDropdownLabel           matlab.ui.control.Label
        PacejkaPDropdown                matlab.ui.control.DropDown
        PacejkaFitAxes                  matlab.ui.control.UIAxes
        LoadSensitivityFitsTab          matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout6                     matlab.ui.container.GridLayout
        UBdegLabel                      matlab.ui.control.Label
        SASlider                        matlab.ui.control.Slider
        LBdegLabel                      matlab.ui.control.Label
        GridLayout2_2                   matlab.ui.container.GridLayout
        Panel_31                        matlab.ui.container.Panel
        FZSAdirDropdown                 matlab.ui.control.DropDown
        FZSAdirDropdownLabel            matlab.ui.control.Label
        Panel_28                        matlab.ui.container.Panel
        FZVaryDropdown                  matlab.ui.control.DropDown
        FZVaryDropdownLabel             matlab.ui.control.Label
        Panel_29                        matlab.ui.container.Panel
        FZIADropdown                    matlab.ui.control.DropDown
        FZIADropdownLabel               matlab.ui.control.Label
        Panel_30                        matlab.ui.container.Panel
        FZPDropdown                     matlab.ui.control.DropDown
        FZPDropdownLabel                matlab.ui.control.Label
        LoadSensAxes                    matlab.ui.control.UIAxes
        SimilarityMethodTab             matlab.ui.container.Tab
        GridLayout25                    matlab.ui.container.GridLayout
        GridLayout4_2                   matlab.ui.container.GridLayout
        Panel_47                        matlab.ui.container.Panel
        SimilarityViewDropdown          matlab.ui.control.DropDown
        ViewDropDownLabel               matlab.ui.control.Label
        Panel_46                        matlab.ui.container.Panel
        SimilaritySAdirDropdownLabel    matlab.ui.control.Label
        SimilaritySAdirDropdown         matlab.ui.control.DropDown
        Panel_45                        matlab.ui.container.Panel
        SimilarityVaryDropdown          matlab.ui.control.DropDown
        SimilarityVaryDropdownLabel     matlab.ui.control.Label
        Panel_44                        matlab.ui.container.Panel
        SimilarityIADropdownLabel       matlab.ui.control.Label
        SimilarityIADropdown            matlab.ui.control.DropDown
        Panel_43                        matlab.ui.container.Panel
        SimilarityFZDropdownLabel       matlab.ui.control.Label
        SimilarityFZDropdown            matlab.ui.control.DropDown
        Panel_42                        matlab.ui.container.Panel
        SimilarityPDropdownLabel        matlab.ui.control.Label
        SimilarityPDropdown             matlab.ui.control.DropDown
        SimilarityAxes                  matlab.ui.control.UIAxes
        TireInformationPanel            matlab.ui.container.Panel
        TireInfoPanelGridLayout         matlab.ui.container.GridLayout
        TireNameLabel                   matlab.ui.control.Label
        TestFormatPanel                 matlab.ui.container.Panel
        TestSectionTree                 matlab.ui.container.Tree
        ErrorLabel                      matlab.ui.control.Label
        TireInfoLabelsGridLayout        matlab.ui.container.GridLayout
        CompoundValueLabel              matlab.ui.control.Label
        ConstructionValueLabel          matlab.ui.control.Label
        RimWidthValueLabel              matlab.ui.control.Label
        RimDiameterValueLabel           matlab.ui.control.Label
        CompoundLabel                   matlab.ui.control.Label
        ConstructionLabel               matlab.ui.control.Label
        RimWidthLabel                   matlab.ui.control.Label
        RimDiameterLabel                matlab.ui.control.Label
        TireDiameterValueLabel          matlab.ui.control.Label
        TireDiameterLabel               matlab.ui.control.Label
        ManufacturerValueLabel          matlab.ui.control.Label
        ManufacturerLabel               matlab.ui.control.Label
        TireWidthValueLabel             matlab.ui.control.Label
        TireWidthLabel                  matlab.ui.control.Label
        TTCRoundValueLabel              matlab.ui.control.Label
        TTCRoundLabel                   matlab.ui.control.Label
        RunOptionsPanel                 matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        TirenotsupportedyetLabel        matlab.ui.control.Label
        GridLayout16                    matlab.ui.container.GridLayout
        RunButton                       matlab.ui.control.Button
        ScalingfactorsPanel             matlab.ui.container.Panel
        YscalingfactorEditField         matlab.ui.control.NumericEditField
        YscalingfactorLabel             matlab.ui.control.Label
        XscalingfactorEditField         matlab.ui.control.NumericEditField
        ScalingfactorLabel              matlab.ui.control.Label
        UsedifferentXandYscalingfactorsCheckBox  matlab.ui.control.CheckBox
        TireSelectionMethodButtonGroup  matlab.ui.container.ButtonGroup
        IndividualparametersButton      matlab.ui.control.RadioButton
        FromdropdownButton              matlab.ui.control.RadioButton
        Panel_11                        matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        CheckBox                        matlab.ui.control.CheckBox
        ConstructionDropdown            matlab.ui.control.DropDown
        ConstructionDropdownLabel       matlab.ui.control.Label
        RimWidthDropdownLabel           matlab.ui.control.Label
        TTCRoundDropdownLabel           matlab.ui.control.Label
        CompoundDropdown                matlab.ui.control.DropDown
        CompoundDropdownLabel           matlab.ui.control.Label
        RimWidthDropdown                matlab.ui.control.DropDown
        RimDiameterDropdown             matlab.ui.control.DropDown
        RimDiameterDropdownLabel        matlab.ui.control.Label
        TireWidthDropdown               matlab.ui.control.DropDown
        TireWidthDropdownLabel          matlab.ui.control.Label
        TireDiameterDropdown            matlab.ui.control.DropDown
        TireDiameterDropdownLabel       matlab.ui.control.Label
        RoundDropdown                   matlab.ui.control.DropDown
        ManufacturerDropdown            matlab.ui.control.DropDown
        ManufacturerDropdownLabel       matlab.ui.control.Label
        CompoundCheckBox                matlab.ui.control.CheckBox
        RimWidthCheckBox                matlab.ui.control.CheckBox
        RimDiameterCheckBox             matlab.ui.control.CheckBox
        TireWidthCheckBox               matlab.ui.control.CheckBox
        TireDiameterCheckBox            matlab.ui.control.CheckBox
        ManufacturerCheckBox            matlab.ui.control.CheckBox
        TTCRoundCheckBox                matlab.ui.control.CheckBox
        Panel_19                        matlab.ui.container.Panel
        TireLabel                       matlab.ui.control.Label
        TireDropdown                    matlab.ui.control.DropDown
        Panel_20                        matlab.ui.container.Panel
        SeparateSACheckBox              matlab.ui.control.CheckBox
        Panel_21                        matlab.ui.container.Panel
        ShowFitOptionsButton            matlab.ui.control.StateButton
        CreatefitsCheckBox              matlab.ui.control.CheckBox
    end

    
    % ----- INFO -----
    % 1. Indexing goes: (sa, sa_dir, load, camber, pressure)
    % 2. Naming convention: camelCase, I guess CamelCase for app properties
    % ----------------
    
    properties (Access = private)
        % Data files info
        TireDataMap containers.Map %#ok<MCHDT> % Tire data variables
        UsedTireData struct % Data from tire actually used
        TireInfoMap containers.Map %#ok<MCHDT> 

        RunOptions struct = struct( ...
            'SaveData', false, ...
            'UseDiffScalingFactors', false, ...
            'XScalingFactor', 1, ...
            'YScalingFactor', 1, ...
            'CreateFits', true, ...
            'SeparateSADir', false, ...
            'SABound', 15, ...
            'SAStepSize', 0.1, ...
            'SASampleVals', 0, ...
            'PacejkaMaxIterations', 3000, ...
            'PacejkaAlgorithm', 'levenberg-marquardt', ...
            'LoadStepSize', 20, ...
            'LoadUpperBound', 1200, ...
            'LoadSampleVals', 0, ...
            'LoadSensOrder', 2 ...
            )
        PacejkaIAorPState CamberOrPressureState
        PacejkaAxesDataHandle
        PacejkaAxesFitHandle
        PacejkaAxesAxisLinesHandles
        
        LoadSensIAorPState CamberOrPressureState
        LoadSensAxesDataHandle
        LoadSensAxesFitHandle
        LoadSensAxesAxisLinesHandle
        
        SimilarityIAorPState CamberOrPressureState
        SimilarityAxesDataHandles
        SimilarityAxesAxisLinesHandles
        
        % Data processing global variables
        DataProcessingDepth = 0;
        DataProcessingCurrentNode
        TestSectionNames
        ProcessingResults
        
        % 
        CurrentProgressDialog
        CurrentProgressDialogStrings
    end
    
    properties (Access = public)
        LatPressureLUTData
        LatCamberLUTData
        LatLUTData
    end
    
    methods (Access = private)
        
        function updateUI(~)
            % For some reason, this works
            % Guess it pauses the main thread and lets the ui thread run...
            pause(0.000001);
        end
        
        function updateTireDetails(app, tireName)
            app.RoundDropdown.Value = num2str(app.TireInfoMap(tireName).TTCRound);
            app.ManufacturerDropdown.Value = app.TireInfoMap(tireName).manufacturer;
            app.TireDiameterDropdown.Value = num2str(app.TireInfoMap(tireName).tireDiameter);
            app.TireWidthDropdown.Value = num2str(app.TireInfoMap(tireName).tireWidth);
            app.RimDiameterDropdown.Value = num2str(app.TireInfoMap(tireName).rimDiameter);
            app.RimWidthDropdown.Value = num2str(app.TireInfoMap(tireName).rimWidth);
            app.ConstructionDropdown.Value = num2str(app.TireInfoMap(tireName).construction);
            app.CompoundDropdown.Value = app.TireInfoMap(tireName).compound;
        end
        
        % Pacejka tab options
        
        function switchPacejkaState(app, state)
            switch state
                case CamberOrPressureState.Camber
                    app.PacejkaVaryDropdown.Enable = 'on';
                    app.PacejkaVaryDropdownLabel.Enable = 'on';
                    app.PacejkaPDropdown.Enable = 'off';
                    app.PacejkaPDropdown.Enable = 'off';
                    app.PacejkaIADropdown.Enable = 'on';
                    app.PacejkaIADropdownLabel.Enable = 'on';
                case CamberOrPressureState.Pressure
                    app.PacejkaVaryDropdown.Enable = 'on';
                    app.PacejkaVaryDropdownLabel.Enable = 'on';
                    app.PacejkaPDropdown.Enable = 'on';
                    app.PacejkaPDropdown.Enable = 'on';
                    app.PacejkaIADropdown.Enable = 'off';
                    app.PacejkaIADropdownLabel.Enable = 'off';
                case CamberOrPressureState.Both
                    app.PacejkaVaryDropdown.Enable = 'off';
                    app.PacejkaVaryDropdownLabel.Enable = 'off';
                    app.PacejkaPDropdown.Enable = 'on';
                    app.PacejkaPDropdown.Enable = 'on';
                    app.PacejkaIADropdown.Enable = 'on';
                    app.PacejkaIADropdownLabel.Enable = 'on';
            end
            app.PacejkaIAorPState = state;
        end
        
        function updatePacejkaDropdowns(app)
            switch app.PacejkaIAorPState
                case CamberOrPressureState.Camber
                    app.PacejkaIADropdown.Items = string(num2cell(app.LatCamberLUTData.iaOptions));
                    app.PacejkaFZDropdown.Items = string(num2cell(app.LatCamberLUTData.fzOptions));
                case CamberOrPressureState.Pressure
                    app.PacejkaPDropdown.Items = string(num2cell(app.LatPressureLUTData.pOptions));
                    app.PacejkaFZDropdown.Items = string(num2cell(app.LatPressureLUTData.fzOptions));
                case CamberOrPressureState.Both
                    app.PacejkaIADropdown.Items = string(num2cell(app.LatLUTData.iaOptions));
                    app.PacejkaPDropdown.Items = string(num2cell(app.LatLUTData.pOptions));
                    app.PacejkaFZDropdown.Items = string(num2cell(app.LatLUTData.fzOptions));
            end
        end
        
        function updatePacejkaStateFromDropdown(app)
            switch app.PacejkaVaryDropdown.Value
                case 'IA'
                    switchPacejkaState(app, CamberOrPressureState.Camber);
                case 'P'
                    switchPacejkaState(app, CamberOrPressureState.Pressure);
            end
        end
        
        function updatePacejkaAxes(app)
            delete(app.PacejkaAxesDataHandle);
            delete(app.PacejkaAxesFitHandle);
            
            fzIndex = getFZIndexFromDropdownPacejka(app);
            
            switch app.PacejkaIAorPState

                case CamberOrPressureState.Camber
                    
                    iaIndex = getIAIndexFromDropdownPacejka(app);
                    xData = app.LatCamberLUTData.saData{fzIndex, iaIndex};
                    yData = app.LatCamberLUTData.nfyData{fzIndex, iaIndex};

                    if app.RunOptions.CreateFits

                        %if app.SeparateSA
                        %    pacejkaY = pacejka(app.PacejkaCoeff(:, SAdirIndex, fzIndex, iaIndex, pIndex), deg2rad(pacejkaX));
                        %else
                        %    pacejkaY = pacejka(app.PacejkaCoeff(:, fzIndex, iaIndex, pIndex), deg2rad(pacejkaX));
                        %end
                        pacejkaY = app.LatCamberLUTData.nfySamplePoints(:, fzIndex, iaIndex);

                        if app.LatCamberLUTData.nfyExitFlags(fzIndex, iaIndex) == 0
                            app.PacejkaFitNotCompletedLabel.Visible = 'on';
                        else
                            app.PacejkaFitNotCompletedLabel.Visible = 'off';
                        end
                    end
                    
                case CamberOrPressureState.Pressure

                    pIndex = getPIndexFromDropdownPacejka(app);
                    xData = app.LatPressureLUTData.saData{fzIndex, pIndex};
                    yData = app.LatPressureLUTData.nfyData{fzIndex, pIndex};

                    if app.RunOptions.CreateFits

                        %if app.SeparateSA
                        %    pacejkaY = pacejka(app.PacejkaCoeff(:, SAdirIndex, fzIndex, iaIndex, pIndex), deg2rad(pacejkaX));
                        %else
                        %    pacejkaY = pacejka(app.PacejkaCoeff(:, fzIndex, iaIndex, pIndex), deg2rad(pacejkaX));
                        %end
                        pacejkaY = app.LatPressureLUTData.nfySamplePoints(:, fzIndex, pIndex);
                        app.PacejkaFitNotCompletedLabel.Visible = app.LatPressureLUTData.nfyExitFlags(fzIndex, pIndex) == 0;
                    end
                    
                case CamberOrPressureState.Both

                    iaIndex = getIAIndexFromDropdownPacejka(app);
                    pIndex = getPIndexFromDropdownPacejka(app);
                    
                    xData = app.LatLUTData.saData{fzIndex, iaIndex, pIndex};
                    yData = app.LatLUTData.nfyData{fzIndex, iaIndex, pIndex};

                    if app.RunOptions.CreateFits
                        pacejkaY = app.LatLUTData.nfySamplePoints(:, fzIndex, iaIndex, pIndex);
                        app.PacejkaFitNotCompletedLabel.Visible = app.LatLUTData.nfyExitFlags(fzIndex, iaIndex, pIndex) == 0;
                    end
            end
            
            app.PacejkaAxesDataHandle = scatter(app.PacejkaFitAxes, xData, yData, 5, 'blue');

            if app.RunOptions.CreateFits
                pacejkaX = app.RunOptions.SASampleVals;
                app.PacejkaAxesFitHandle = plot(app.PacejkaFitAxes, pacejkaX, pacejkaY, 'red');
            end
            
            legend(app.PacejkaFitAxes, {'', '', 'Test data', 'Magic formula fit'}, 'Location', 'northwest');
        end
        
        function structName = getStructNameFromPacejkaState(app)
            switch app.PacejkaIAorPState
                case CamberOrPressureState.Camber
                    structName = 'LatCamberLUTData';
                case CamberOrPressureState.Pressure
                    structName = 'LatPressureLUTData';
                case CamberOrPressureState.Both
                    structName = 'LatLUTData';
            end
        end
        
        function fzIndex = getFZIndexFromDropdownPacejka(app)
            structName = getStructNameFromPacejkaState(app);
            value = str2double(app.PacejkaFZDropdown.Value);
            fzIndex = find(app.(structName).fzOptions == value);
        end
        
        function iaIndex = getIAIndexFromDropdownPacejka(app)
            structName = getStructNameFromPacejkaState(app);
            value = str2double(app.PacejkaIADropdown.Value);
            iaIndex = find(app.(structName).iaOptions == value);
        end
        
        function pIndex = getPIndexFromDropdownPacejka(app)
            structName = getStructNameFromPacejkaState(app);
            value = str2double(app.PacejkaPDropdown.Value);
            pIndex = find(app.(structName).pOptions == value);
        end
        
        % Load sensitivity tab options
        
        function switchLoadSensState(app, state)
            switch state
                case CamberOrPressureState.Camber
                    app.FZPDropdown.Enable = 'off';
                    app.FZIADropdown.Enable = 'on';
                case CamberOrPressureState.Pressure
                    app.FZPDropdown.Enable = 'on';
                    app.FZIADropdown.Enable = 'off';
                case CamberOrPressureState.Both
                    app.FZVaryDropdown.Enable = 'off';
                    app.FZPDropdown.Enable = 'on';
                    app.FZIADropdown.Enable = 'on';
            end
            app.LoadSensIAorPState = state;
        end
        
        function updateLoadSensDropdowns(app)
            switch app.LoadSensIAorPState
                case CamberOrPressureState.Camber
                    app.FZIADropdown.Items = string(num2cell(app.LatCamberLUTData.iaOptions));
                case CamberOrPressureState.Pressure
                    app.FZPDropdown.Items = string(num2cell(app.LatPressureLUTData.pOptions));
                case CamberOrPressureState.Both
                    app.FZIADropdown.Items = string(num2cell(app.LatLUTData.iaOptions));
                    app.FZPDropdown.Items = string(num2cell(app.LatLUTData.pOptions));
            end
        end
        
        function updateLoadSensStateFromDropdown(app)
            switch app.FZVaryDropdown.Value
                case 'IA'
                    switchLoadSensState(app, CamberOrPressureState.Camber);
                case 'P'
                    switchLoadSensState(app, CamberOrPressureState.Pressure);
            end
        end
        
        function updateLoadSensAxesAuto(app)
            saIndex = find(app.RunOptions.SASampleVals == app.SASlider.Value);
            if isempty(saIndex)
                error(['Matt error: no saIndex found. Slider value: ' num2str(app.SASlider.Value)])
            end
            updateLoadSensAxesGivenSAIndex(app, saIndex);
        end
        
        function updateLoadSensAxesWithRound(app, newVal)
            [~, ind] = min(abs(app.RunOptions.SASampleVals - newVal));
            roundedVal = app.RunOptions.SASampleVals(ind);
            if roundedVal ~= app.SASlider.Value
                updateLoadSensAxesGivenSAIndex(app, ind);
                app.SASlider.Value = roundedVal;
            end
        end
        
        function updateLoadSensAxesGivenSAIndex(app, saIndex)
            delete(app.LoadSensAxesDataHandle);
            delete(app.LoadSensAxesFitHandle);
            
            switch app.LoadSensIAorPState
                case CamberOrPressureState.Camber
                    iaIndex = getIAIndexFromStringLoadSens(app);
            
                    % Plot data points
                    actualLoadVals = app.LatCamberLUTData.meanLoads(:, iaIndex);
                    
                    actualCoeff = app.LatCamberLUTData.nfySamplePoints(saIndex, :, iaIndex);
                    
                    % Plot fit line
                    xvals = -app.RunOptions.LoadSampleVals;
                    yvals = app.LatCamberLUTData.nfyVals(saIndex, :, iaIndex);

                    drawnow

                case CamberOrPressureState.Pressure
                    pIndex = getPIndexFromStringLoadSens(app);
                    
                    % Plot data points
                    actualLoadVals = app.LatPressureLUTData.meanLoads(:, pIndex);
                    actualCoeff = app.LatPressureLUTData.nfySamplePoints(saIndex, :, pIndex);
                    
                    % Plot fit line
                    xvals = -app.RunOptions.LoadSampleVals;
                    yvals = app.LatPressureLUTData.nfyVals(saIndex, :, pIndex);
                    
                    drawnow

                case CamberOrPressureState.Both
                    iaIndex = getIAIndexFromStringLoadSens(app);
                    pIndex = getPIndexFromStringLoadSens(app);
                    
                    % Plot data points
                    actualLoadVals = app.LatLUTData.meanLoads(:, iaIndex, pIndex);
                    actualCoeff = app.LatLUTData.nfySamplePoints(saIndex, :, iaIndex, pIndex);
                    
                    % Plot fit line
                    xvals = -app.RunOptions.LoadSampleVals;
                    yvals = app.LatLUTData.nfyVals(saIndex, :, iaIndex, pIndex);
                    
                    drawnow
            end
            
            app.LoadSensAxesDataHandle = scatter(app.LoadSensAxes, actualLoadVals, actualCoeff, 'magenta');
            
            app.LoadSensAxesFitHandle = line(app.LoadSensAxes, 'XData', xvals, 'YData', yvals, 'Color', 'cyan');
            
            legend(app.LoadSensAxes, {'', 'Test data', 'Polynomial fit'}, 'Location', 'northeast');
        end
        
        function structName = getStructNameFromLoadSensState(app)
            switch app.LoadSensIAorPState
                case CamberOrPressureState.Camber
                    structName = 'LatCamberLUTData';
                case CamberOrPressureState.Pressure
                    structName = 'LatPressureLUTData';
                case CamberOrPressureState.Both
                    structName = 'LatLUTData';
            end
        end
        
        function iaIndex = getIAIndexFromStringLoadSens(app)
            structName = getStructNameFromLoadSensState(app);
            value = str2double(app.FZIADropdown.Value);
            iaIndex = find(app.(structName).iaOptions == value);
        end
        
        function pIndex = getPIndexFromStringLoadSens(app)
            structName = getStructNameFromLoadSensState(app);
            value = str2double(app.FZPDropdown.Value);
            pIndex = find(app.(structName).pOptions == value);
        end
        
        % Similarity method tab functions
        
        function switchSimilarityState(app, state)
            switch state
                case CamberOrPressureState.Camber
                    app.SimilarityPDropdown.Enable = 'off';
                    app.SimilarityIADropdown.Enable = 'on';
                case CamberOrPressureState.Pressure
                    app.SimilarityPDropdown.Enable = 'on';
                    app.SimilarityIADropdown.Enable = 'off';
                case CamberOrPressureState.Both
                    app.SimilarityVaryDropdown.Enable = 'off';
                    app.SimilarityPDropdown.Enable = 'on';
                    app.SimilarityIADropdown.Enable = 'on';
            end
            app.SimilarityIAorPState = state;
        end
        
        function updateSimilarityDropdowns(app)
            switch app.SimilarityIAorPState
                case CamberOrPressureState.Camber
                    app.SimilarityFZDropdown.Items = string(num2cell(app.LatCamberLUTData.fzOptions));
                    app.SimilarityIADropdown.Items = string(num2cell(app.LatCamberLUTData.iaOptions));
                case CamberOrPressureState.Pressure
                    app.SimilarityFZDropdown.Items = string(num2cell(app.LatPressureLUTData.fzOptions));
                    app.SimilarityPDropdown.Items = string(num2cell(app.LatPressureLUTData.pOptions));
                case CamberOrPressureState.Both
                    app.SimilarityFZDropdown.Items = string(num2cell(app.LatLUTData.fzOptions));
                    app.SimilarityIADropdown.Items = string(num2cell(app.LatLUTData.iaOptions));
                    app.SimilarityPDropdown.Items = string(num2cell(app.LatLUTData.pOptions));
            end
        end
        
        function updateSimilarityStateFromDropdown(app)
            switch app.SimilarityVaryDropdown.Value
                case 'IA'
                    switchSimilarityState(app, CamberOrPressureState.Camber);
                case 'P'
                    switchSimilarityState(app, CamberOrPressureState.Pressure);
            end
        end
        
        function updateSimilarityAxes(app)
            
            if ~isempty(app.SimilarityAxesDataHandles)
                delete(app.SimilarityAxesDataHandles(1));
                delete(app.SimilarityAxesDataHandles(2));
                delete(app.SimilarityAxesAxisLinesHandles(2));
            end

            viewingMZ = app.SimilarityViewDropdown.Value == 'MZ';
            
            fzIndex = getFZIndexFromStringSimilarity(app);
            
            switch app.SimilarityIAorPState
                
                case CamberOrPressureState.Camber

                    iaIndex = getIAIndexFromStringSimilarity(app);
                    
                    xDataReal = app.LatCamberLUTData.saData{fzIndex, iaIndex};
                    xDataAdj = app.LatCamberLUTData.alpha_adj{iaIndex}(:, fzIndex);
                    
                    if viewingMZ
                        yDataReal = app.LatCamberLUTData.mzData{fzIndex, iaIndex};
                        yDataAdj = app.LatCamberLUTData.MZ_adj{iaIndex}(:, fzIndex);
                    else
                        yDataReal = app.LatCamberLUTData.nfyData{fzIndex, iaIndex} .* app.LatCamberLUTData.fzData{fzIndex, iaIndex};
                        yDataAdj = app.LatCamberLUTData.FY_adj{iaIndex}(:, fzIndex);
                    end
                    
                case CamberOrPressureState.Pressure
                    
                    pIndex = getPIndexFromStringSimilarity(app);
                    
                    xDataReal = app.LatPressureLUTData.saData{fzIndex, pIndex};
                    xDataAdj = app.LatPressureLUTData.alpha_adj{pIndex}(:, fzIndex);

                    if viewingMZ
                        yDataReal = app.LatPressureLUTData.mzData{fzIndex, pIndex};
                        yDataAdj = app.LatPressureLUTData.MZ_adj{pIndex}(:, fzIndex);
                    else
                        yDataReal = app.LatPressureLUTData.nfyData{fzIndex, pIndex} .* app.LatCamberLUTData.fzData{fzIndex, pIndex};
                        yDataAdj = app.LatPressureLUTData.FY_adj{pIndex}(:, fzIndex);
                    end
                    
                case CamberOrPressureState.Both
                    
                    iaIndex = getIAIndexFromStringSimilarity(app);
                    pIndex = getPIndexFromStringSimilarity(app);
                    
                    xDataReal = app.LatLUTData.saData{fzIndex, iaIndex, pIndex};
                    xDataAdj = app.LatLUTData.alpha_adj{iaIndex, pIndex}(:, fzIndex);
                    
                    if viewingMZ
                        yDataReal = app.LatLUTData.mzData{fzIndex, iaIndex, pIndex};
                        yDataAdj = app.LatLUTData.MZ_adj{iaIndex, pIndex}(:, fzIndex);
                    else
                        yDataReal = app.LatLUTData.nfyData{fzIndex, iaIndex, pIndex} .* app.LatLUTData.fzData{fzIndex, iaIndex, pIndex};
                        yDataAdj = app.LatLUTData.FY_adj{iaIndex, pIndex}(:, fzIndex);
                    end
                    
            end

            if viewingMZ
                ylabel(app.SimilarityAxes, "Aligning Torque (Nm)")
                yAddition = 5;
            else
                ylabel(app.SimilarityAxes, "Lateral Force (N)")
                yAddition = 50;
            end

            yLimit = max([yDataReal; yDataAdj]) + yAddition;

            app.SimilarityAxesAxisLinesHandles(2) = plot(app.SimilarityAxes, [0 0], [-yLimit yLimit], 'black');
            
            app.SimilarityAxesDataHandles(2) = scatter(app.SimilarityAxes, xDataReal, yDataReal, 5, 'red');
            app.SimilarityAxesDataHandles(1) = scatter(app.SimilarityAxes, xDataAdj, yDataAdj, 5, 'cyan');

            axis(app.SimilarityAxes, [-app.RunOptions.SABound app.RunOptions.SABound -yLimit yLimit]);
            
            legend(app.SimilarityAxes, ["", "", "Real data", "Reference data adjusted"], 'Location', 'northeast');

        end
        
        function structName = getStructNameFromSimilarityState(app)
            switch app.SimilarityIAorPState
                case CamberOrPressureState.Camber
                    structName = 'LatCamberLUTData';
                case CamberOrPressureState.Pressure
                    structName = 'LatPressureLUTData';
                case CamberOrPressureState.Both
                    structName = 'LatLUTData';
            end
        end

        function fzIndex = getFZIndexFromStringSimilarity(app)
            structName = getStructNameFromSimilarityState(app);
            value = str2double(app.SimilarityFZDropdown.Value);
            fzIndex = find(app.(structName).fzOptions == value);
        end

        function iaIndex = getIAIndexFromStringSimilarity(app)
            structName = getStructNameFromSimilarityState(app);
            value = str2double(app.SimilarityIADropdown.Value);
            iaIndex = find(app.(structName).iaOptions == value);
        end
        
        function pIndex = getPIndexFromStringSimilarity(app)
            structName = getStructNameFromSimilarityState(app);
            value = str2double(app.SimilarityPDropdown.Value);
            pIndex = find(app.(structName).pOptions == value);
        end
        
        % End of tab group
        
        function suggestLoadBound(app)
            tireName = app.TireDropdown.Value;
            maxLoad = max(-app.TireDataMap(tireName).FZ);
            maxRoundedLoad = ceil(maxLoad/100) * 100;
            app.LoadUBEditField.Value = maxRoundedLoad;
        end
        
        function updateProgressDialogPrimaryMessage(app, text, keepOtherText)
            if nargin < 3
                keepOtherText = false;
            end
            assert(isa(text, 'string'), 'Text must be a string')
            %text = "<b>" + text + "</b>"; % Commented out until I know how to set interpreter to HTML again (removed in R2021a)
            if keepOtherText
                app.CurrentProgressDialogStrings(1) = text;
            else
                app.CurrentProgressDialogStrings = text;
            end
            app.CurrentProgressDialog.Message = app.CurrentProgressDialogStrings;
        end
        
        function updateProgressDialogSecondaryMessage(app, text, keepOtherText)
            if nargin < 3
                keepOtherText = false;
            end
            assert(isa(text, 'string'), 'Text must be a string')
            app.CurrentProgressDialogStrings(2) = "    " + text;
            if ~keepOtherText
                app.CurrentProgressDialogStrings = app.CurrentProgressDialogStrings(1:2);
            end
            app.CurrentProgressDialog.Message = app.CurrentProgressDialogStrings;
        end
        
        function updateProgressDialogTertiaryMessage(app, text, keepOtherText)
            assert(isa(text, 'string'), 'Text must be a string')
            app.CurrentProgressDialogStrings(3) = "        " + text;
            if ~keepOtherText
                app.CurrentProgressDialogStrings = app.CurrentProgressDialogStrings(1:3);
            end
            app.CurrentProgressDialog.Message = app.CurrentProgressDialogStrings;
        end

        function updateProgressDialogQuarternaryMessage(app, text)
            assert(isa(text, 'string'), 'Text must be a string')
            app.CurrentProgressDialogStrings(4) = "            " + text;
            app.CurrentProgressDialog.Message = app.CurrentProgressDialogStrings;
        end
        
        function [processingResults, upperBound] = processTestSection(app, testSection, startIndex)
            
            % Increment depth counter at start of function
            app.DataProcessingDepth = app.DataProcessingDepth + 1;
            
            nGroups = testSection.BFinder.getN();
            assert(nGroups > 0, 'Matt error: nGroups less than 1')
            nChildren = length(testSection.Children);
            
            recordSection = ~ismember(testSection.Name, app.TestSectionNames) && testSection.Displayed;
            hasMultipleGroups = nGroups > 1;
            
            app.CurrentProgressDialog.Value = startIndex / length(app.UsedTireData.ET);
            
            if ~hasMultipleGroups
                switch app.DataProcessingDepth
                    case 1
                        app.updateProgressDialogSecondaryMessage(testSection.Name, true)
                    case 2
                        app.updateProgressDialogTertiaryMessage(testSection.Name, true)
                    case 3
                        app.updateProgressDialogQuarternaryMessage(testSection.Name)
                end
            end
            
            if hasMultipleGroups
                name = testSection.Name + " (" + nGroups + "x)";
            else
                name = testSection.Name;
            end
            
            if recordSection
                app.TestSectionNames(end + 1) = testSection.Name;
                treeNode = uitreenode(app.DataProcessingCurrentNode, "Text", name);
                expand(app.TestSectionTree);
                expand(app.DataProcessingCurrentNode);
                app.updateUI();
                % Save the old node and temporarily set this node to be the current (so children can reference it as the parent)
                oldNode = app.DataProcessingCurrentNode;
                app.DataProcessingCurrentNode = treeNode;
            end
            
            [lowerBounds, upperBounds] = testSection.getBounds(app.UsedTireData, startIndex);
            
            disp("Depth: " + app.DataProcessingDepth + ", finished test: " + name + ", LB: " + mat2str(lowerBounds) + ", UB: " + mat2str(upperBounds))
            
            childrenResults = {};
            
            for i = 1:nGroups
                
                if hasMultipleGroups

                    name = testSection.Name + " " + i + "/" + nGroups;

                    switch app.DataProcessingDepth
                        case 1
                            app.updateProgressDialogSecondaryMessage(name, true)
                        case 2
                            app.updateProgressDialogTertiaryMessage(name, true)
                        case 3
                            app.updateProgressDialogQuarternaryMessage(name)
                    end
                end
                
                lb_i = lowerBounds(i);
                currentIndex = lb_i;
                
                for j = 1:nChildren
                    
                    child = testSection.Children(j);
                    
                    [childResults, ub] = app.processTestSection(child, currentIndex);
                    currentIndex = ub(end);
                    childrenResults{j}(i) = childResults; %#ok<AGROW> 
                end
                
            end
            
            if recordSection
                % Reset back to the old node once this section's children have finished
                app.DataProcessingCurrentNode = oldNode;
            end
            
            if ~exist('childrenResults', 'var')
                childrenResults = [];
            end
            processingResults = testSection.processData(app, subsetOfStruct(app.UsedTireData, [lowerBounds(1) upperBounds(end)]), childrenResults, app.RunOptions);
            upperBound = upperBounds(end);
            
            if app.DataProcessingDepth == 1 && upperBound == length(app.UsedTireData.ET)
                app.ProcessingResults = processingResults;
            end
            
            % Now decrement depth counter
            app.DataProcessingDepth = app.DataProcessingDepth - 1;
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CurrentProgressDialog = uiprogressdlg(app.TireAnalyzerUIFigure);%, 'interpreter', 'html');
            app.PacejkaFitAxes.Visible = false;
            app.LoadSensAxes.Visible = false;
            app.PacejkaFitNotCompletedLabel.Visible = false;
            app.RunOptionsPanel.Enable = 'off';
            
            app.TabGroup.Visible = false;
            app.TireInformationPanel.Visible = false;
            app.FitOptionsPanel.Parent = app.BottomGridLayout;
            app.FitOptionsPanel.Layout.Column = 2;
            app.FitOptionsPanel.Layout.Row = 1;
            app.FitOptionsPanel.Visible = false;
            updateUI(app);
            
            % Empty map which wil be used to map each set of data to the tire name
            app.TireDataMap = containers.Map('KeyType', 'char', 'ValueType', 'any');
            
            % Load files in tire data directory
            dataFolder = "./data";
            folderContents = what(dataFolder);
            % Only list .mat files
            matFiles = natsortfiles(folderContents.mat);
            % Number of files
            nFiles = length(matFiles);
            
            % Load each tire's name into the array
            updateUI(app);
            app.updateProgressDialogPrimaryMessage("Loading data from files into workspace...");
            pause(1.2) % Necessary for progress dialog to show up, for some reason
            updateUI(app);

            for i = 1:nFiles
                app.updateProgressDialogSecondaryMessage("Loading file " + i + "/" + nFiles);
                app.CurrentProgressDialog.Value = i / nFiles;
                filePath = strcat(dataFolder + "/", matFiles{i});
                % Load the tire info and data
                tireData = load(filePath);
                tireDataRaw{i} = tireData; %#ok<AGROW> 
            end
            
            % Concatenate the data from different files but same tire
            app.updateProgressDialogPrimaryMessage("Concantenating data...");

            tireNamesSorted = {};
            
            for i = 1:length(tireDataRaw) % Iterate over tire data entries (one for each file)
                tireName = tireDataRaw{i}.tireid;
                
                if contains(tireName, '--')
                    tireName = extractBetween(tireName, '', ' --');
                    tireName = tireName{1};
                end
                
                fieldNames = fieldnames(tireDataRaw{i});
                
                isNew = ~ismember(tireName, app.TireDataMap.keys);
                
                if isNew
                    dataStruct = struct();
                else
                    originalData = app.TireDataMap(tireName);
                end
                
                newData = tireDataRaw{i};
                
                for j = 1:numel(fieldNames) % Iterate over field names
                    fieldNameCell = fieldNames(j);
                    fieldName = fieldNameCell{1};
                    if ~strcmp(fieldNames{j}, 'channel') ...
                            && ~strcmp(fieldNames{j}, 'source') ...
                            && ~strcmp(fieldNames{j}, 'testid') ...
                            && ~strcmp(fieldNames{j}, 'tireid') % Don't concatenate these fields
                    
                        if isNew % If this is the first file for this tire
                            dataStruct.(fieldName) = newData.(fieldName);
                        else % If this is a tire that already came up
                            originalData.(fieldName) = ...
                                [originalData.(fieldName); newData.(fieldName)];
                        end
                    end
                end
                % Less than optimal solution since this has to be checked
                % again... but it's preferred over iterating over field
                % names first and then tires
                if isNew
                    app.TireDataMap(tireName) = dataStruct;
                    tireNamesSorted{end + 1} = tireName; %#ok<AGROW> 
                else
                    app.TireDataMap(tireName) = originalData;
                end
            end

            table = readtable('TireInfo.xlsx');
            for i = 1:height(table)
                tireName = table{i, 1}{1};
                TTCRound = table{i, 2};
                manufacturer = table{i, 3}{1};
                tireDiameter = table{i, 4};
                tireWidth = table{i, 5};
                rimDiameter = table{i, 6};
                rimWidth = table{i, 7};
                construction = table{i, 8}{1};
                if isempty(construction)
                    construction = 'Unknown';
                end
                compound = table{i, 9}{1};
                if isempty(compound)
                    compound = 'Unknown';
                end
                app.TireInfoMap(tireName) = struct( ...
                        'TTCRound', TTCRound, ...
                        'manufacturer', manufacturer, ...
                        'tireDiameter', tireDiameter, ...
                        'tireWidth', tireWidth, ...
                        'rimDiameter', rimDiameter, ...
                        'rimWidth', rimWidth, ...
                        'construction', construction, ...
                        'compound', compound);

                % Fill out the dropdowns
                if ~ismember(num2str(TTCRound), app.RoundDropdown.Items)
                    app.RoundDropdown.Items{end + 1} = num2str(TTCRound);
                end
                if ~ismember(manufacturer, app.ManufacturerDropdown.Items)
                    app.ManufacturerDropdown.Items{end + 1} = manufacturer;
                end
                if ~ismember(num2str(tireDiameter), app.TireDiameterDropdown.Items)
                    app.TireDiameterDropdown.Items{end + 1} = num2str(tireDiameter);
                end
                if ~ismember(num2str(tireWidth), app.TireWidthDropdown.Items)
                    app.TireWidthDropdown.Items{end + 1} = num2str(tireWidth);
                end
                if ~ismember(num2str(rimDiameter), app.RimDiameterDropdown.Items)
                    app.RimDiameterDropdown.Items{end + 1} = num2str(rimDiameter);
                end
                if ~ismember(num2str(rimWidth), app.RimWidthDropdown.Items)
                    app.RimWidthDropdown.Items{end + 1} = num2str(rimWidth);
                end
                if ~ismember(construction, app.ConstructionDropdown.Items)
                    app.ConstructionDropdown.Items{end + 1} = construction;
                end
                if ~ismember(compound, app.CompoundDropdown.Items)
                    app.CompoundDropdown.Items{end + 1} = compound;
                end
            end
            
            app.TireDropdown.Items = tireNamesSorted;
            updateTireDetails(app, app.TireDropdown.Value);
            suggestLoadBound(app);
            
            % UI stuff
            updateUI(app);
            disableDefaultInteractivity(app.PacejkaFitAxes);
            disableDefaultInteractivity(app.LoadSensAxes);
            disableDefaultInteractivity(app.SimilarityAxes);
            app.PacejkaFitAxes.Toolbar.Visible = false;
            app.LoadSensAxes.Toolbar.Visible = false;
            app.SimilarityAxes.Toolbar.Visible = false;
            app.RunOptionsPanel.Enable = 'on';

            app.CurrentProgressDialog.close();
        end

        % Value changed function: TireDropdown
        function tireDropdownChanged(app, event)
            value = app.TireDropdown.Value;
            updateTireDetails(app, value);
            suggestLoadBound(app);
            
            unsupportedTires = {
                'Dunlop 175/505R13 on 8 in. wide rim', ... % Round 4
                'Goodyear 20.0x7.0-13 "D2696" on 7 in. wide rim', ...
                'Goodyear 20.0x7.0-13 "D2696" on 8 in. wide rim', ...
                'Goodyear 20.0x7.0-13 "D2696" on 9 in. wide rim', ...
                'Hoosier 20.0x7.5-13 R25B on 7 in. wide rim', ...
                'Hoosier 20.0x7.5-13 R25B on 8 in. wide rim', ...
                'Goodyear  D2704  20.0x7.0-13  on 7 in. wide rim', ... % Round 5
                'Goodyear  D2704  20.0x7.0-13 on 8 in. wide rim', ...
                'Hoosier  18.0 x 6.0 - 10  R25B on 7 in. wide rim', ...
                'Hoosier  20.5 x 7.0 - 13  R25B on 6 in. wide rim', ...
                'Hoosier 20.5 x 6.0 13 R25B A2500 (Item 43127), 6 inch rim', ... % Round 6
                'Hoosier 18.0 x 7.5 10 R25B  (Item 43105), 7 inch rim', ...
                'Hoosier 18.0 x 7.5 10 R25B  (Item 43105), 8 inch rim', ...
                'Hoosier 18.0 x 6.0 10 R25B (Item 43101), 6 inch rim', ...
                'Hoosier 18.0 x 6.0 10 R25B (Item 43101), 7 inch rim', ...
                'Hoosier 6.0 / 18.0 10 LCO C2000 (Item 41100), 6 inch rim', ...
                'Hoosier 6.0 / 18.0 10 LCO C2000 (Item 41100), 7 inch rim', ...
                'Avon 7.0/16.0-10, 7 inch rim', ... % Round 7
                'Avon 7.0/16.0-10, 8 inch rim', ...
                'Goodyear 20x7-13 D2704, 7 inch rim', ... % Round 8
                'Continental 205/470R13 43329, 7 inch rim', ...
                'Goodyear 20x7-13 D2704, 8 inch rim', ...
                };
            if ismember(value, unsupportedTires)
                app.TirenotsupportedyetLabel.Visible = true;
                app.RunButton.Enable = 'off';
            else
                app.TirenotsupportedyetLabel.Visible = false;
                app.RunButton.Enable = 'on';
            end
            
            
        end

        % Selection changed function: TireSelectionMethodButtonGroup
        function tireSelectionMethodChanged(app, event)
            useDropdown = app.TireSelectionMethodButtonGroup.SelectedObject == app.FromdropdownButton;
            app.TireDropdown.Enable = useDropdown;
            app.RoundDropdown.Enable = ~useDropdown;
            app.ManufacturerDropdown.Enable = ~useDropdown;
            app.TireDiameterDropdown.Enable = ~useDropdown;
            app.TireWidthDropdown.Enable = ~useDropdown;
            app.RimDiameterDropdown.Enable = ~useDropdown;
        end

        % Value changed function: UsedifferentXandYscalingfactorsCheckBox
        function useDifferentScalingFactorsChanged(app, event)
            value = app.UsedifferentXandYscalingfactorsCheckBox.Value;
            app.YscalingfactorEditField.Visible = value;
            app.YscalingfactorLabel.Visible = value;
            if (~value)
                app.ScalingfactorLabel.Text = 'Scaling factor:';
            else
                app.ScalingfactorLabel.Text = 'Slip (X) scaling factor:';
            end
        end

        % Value changed function: SeparateSACheckBox
        function separateSAValueChanged(app, event)
            value = app.SeparateSACheckBox.Value;
            app.SaveFyandMzdatainLUTCheckBox.Value = false;
            app.SaveFyandMzdatainLUTCheckBox.Enable = ~value;
        end

        % Value changed function: CreatefitsCheckBox
        function createFitsCheckboxChanged(app, event)
            createFits = app.CreatefitsCheckBox.Value;
            if createFits
                app.ShowFitOptionsButton.Enable = 'on';
            else
                app.ShowFitOptionsButton.Value = false;
                app.FitOptionsPanel.Visible = false;
                app.ShowFitOptionsButton.Enable = 'off';
            end
        end

        % Button pushed function: RunButton
        function runButtonPushed(app, event)
            app.CurrentProgressDialog = uiprogressdlg(app.TireAnalyzerUIFigure);%, 'Interpreter', 'html');
            app.updateProgressDialogPrimaryMessage("Separating and processing data...");
            updateUI(app);
            % Disable all relevant ui elements while loading
            app.RunOptionsPanel.Enable = 'off';
            app.TabGroup.Visible = false;
            app.TireInformationPanel.Visible = false;
            
            tireName = app.TireDropdown.Value;
            infoStruct = app.TireInfoMap(tireName);
            app.TTCRoundValueLabel.Text = num2str(infoStruct.TTCRound);
            app.ManufacturerValueLabel.Text = infoStruct.manufacturer;
            app.TireDiameterValueLabel.Text = num2str(infoStruct.tireDiameter);
            app.TireWidthValueLabel.Text = num2str(infoStruct.tireWidth);
            app.RimDiameterValueLabel.Text = num2str(infoStruct.rimDiameter);
            app.RimWidthValueLabel.Text = num2str(infoStruct.rimWidth);
            app.ConstructionValueLabel.Text = infoStruct.construction;
            app.CompoundValueLabel.Text = infoStruct.compound;
            
            app.UsedTireData = app.TireDataMap(tireName);
            
            % Fill out RunOptions struct
            app.RunOptions.SaveData = app.SaveFyandMzdatainLUTCheckBox.Value;
            app.RunOptions.UseDiffScalingFactors = app.UsedifferentXandYscalingfactorsCheckBox.Value;
            app.RunOptions.XScalingFactor = app.XscalingfactorEditField.Value;
            if app.UsedifferentXandYscalingfactorsCheckBox.Value
                app.RunOptions.YScalingFactor = app.YscalingfactorEditField.Value;
            else
                app.RunOptions.YScalingFactor = app.RunOptions.XScalingFactor;
            end
            app.RunOptions.CreateFits = app.CreatefitsCheckBox.Value;
            app.RunOptions.SeparateSADir = app.SeparateSACheckBox.Value;
            app.RunOptions.SABound = app.SABoundEditField.Value;
            app.RunOptions.SAStepSize = app.SAStepSizeEditField.Value;
            app.RunOptions.SASampleVals = -app.RunOptions.SABound:app.RunOptions.SAStepSize:app.RunOptions.SABound;
            app.RunOptions.PacejkaMaxIterations = app.FitmaxiterationsEditField.Value;
            app.RunOptions.PacejkaAlgorithm = app.FitalgorithmDropdown.Value;
            app.RunOptions.LoadStepSize = app.LoadStepSizeEditField.Value;
            app.RunOptions.LoadUpperBound = app.LoadUBEditField.Value;
            app.RunOptions.LoadSampleVals = -app.RunOptions.LoadUpperBound:app.RunOptions.LoadStepSize:0;
            app.RunOptions.LoadSensOrder = app.LoadSensitivityOrderSpinner.Value;
            app.RunOptions.SimilarityLoadAdjustment = app.LoadAdjustmentCheckbox.Value;
            app.RunOptions.SimilarityStiffnessAdjustment = app.StiffnessAdjustmentCheckbox.Value;
            app.RunOptions.SimilarityCoFAdjustment = app.CoFAdjustmentCheckbox.Value;
            app.RunOptions.SimilarityCamberShift = app.CamberShiftCheckbox.Value;
            
            testFormat = [];
            % Find the test format for this round
            testFormats = TestFormatSpec.getTestFormats();
            nRounds = length(testFormats);
            for i = 1:nRounds
                % If this is the correct round
                if testFormats(i).RoundNum == infoStruct.TTCRound
                    testFormat = testFormats(i);
                    break;
                end
            end
            
            if isempty(testFormat)
                error(['Matt error: invalid round: ' infoStruct.TTCRound])
            end
            
            app.TestSectionNames = string();
            app.TestSectionTree.Children.delete;
            app.DataProcessingCurrentNode = uitreenode(app.TestSectionTree, 'Text', 'Top level');
            currentIndex = 0;
            for i = 1:length(testFormat.Tests)
                [~, ub] = app.processTestSection(testFormat.Tests(i), currentIndex);
                currentIndex = ub(end);
            end
            
            if isempty(app.UsedTireData)
                error('Matt error: tire not found');
            end
            
            app.TireNameLabel.Text = app.TireDropdown.Value;
            app.ErrorLabel.Visible = false;

            updateUI(app);
            
            if ~isempty(app.LatLUTData)
                switchPacejkaState(app, CamberOrPressureState.Both);
                switchLoadSensState(app, CamberOrPressureState.Both);
                switchSimilarityState(app, CamberOrPressureState.Both);
            else
                updatePacejkaStateFromDropdown(app);
                updateLoadSensStateFromDropdown(app);
                updateSimilarityStateFromDropdown(app);
            end
            
            updatePacejkaDropdowns(app);
            updateLoadSensDropdowns(app);
            updateSimilarityDropdowns(app);
            
            app.updateProgressDialogPrimaryMessage("Plotting Pacejka fits...");
            updateUI(app);
            
            if length(app.PacejkaAxesAxisLinesHandles) > 1
                delete(app.PacejkaAxesAxisLinesHandles(1));
                delete(app.PacejkaAxesAxisLinesHandles(2));
                delete(app.LoadSensAxesAxisLinesHandle);
                delete(app.SimilarityAxesAxisLinesHandles(1));
            end
            
            % Find maximum NFY from all the data
            if isempty(app.LatLUTData)
                maxNFY = max([vertcat(app.LatCamberLUTData.nfyData{:}); vertcat(app.LatPressureLUTData.nfyData{:})]);
            else
                maxNFY = max(vertcat(app.LatLUTData.nfyData{:}));
            end
            
            yLimit = maxNFY + 0.1;
            
            hold(app.PacejkaFitAxes, "on");
            app.PacejkaAxesAxisLinesHandles(1) = plot(app.PacejkaFitAxes, app.RunOptions.XScalingFactor*[-15 15], [0 0], 'black');
            app.PacejkaAxesAxisLinesHandles(2) = plot(app.PacejkaFitAxes, [0 0], app.RunOptions.YScalingFactor*[-yLimit yLimit], 'black');
            
            hold(app.LoadSensAxes, "on");
            app.LoadSensAxesAxisLinesHandle = plot(app.LoadSensAxes, [0 app.RunOptions.LoadUpperBound], [0 0], 'black');
            axis(app.LoadSensAxes, [0 app.RunOptions.LoadUpperBound -yLimit yLimit])
            
            hold(app.SimilarityAxes, "on");
            app.SimilarityAxesAxisLinesHandles(1) = plot(app.SimilarityAxes, app.RunOptions.XScalingFactor*[-15 15], [0 0], 'black');
            
            updatePacejkaAxes(app);
            updateLoadSensAxesAuto(app);
            updateSimilarityAxes(app);
            
            app.PacejkaFitAxes.Visible = true;
            app.LoadSensAxes.Visible = true;
            app.SimilarityAxes.Visible = true;
            
            updateUI(app);
            
            if app.PacejkaIAorPState == CamberOrPressureState.Both
                nSuccess = sum(ismember([app.LatLUTData.nfyExitFlags app.LatLUTData.mzExitFlags], [1 2 3]), 'all');
                nTotalFits = numel([app.LatLUTData.nfyExitFlags app.LatLUTData.mzExitFlags]);
            else
                nSuccess = sum(ismember([app.LatCamberLUTData.nfyExitFlags app.LatCamberLUTData.mzExitFlags], [1 2 3]), 'all') + ...
                    sum(ismember([app.LatPressureLUTData.nfyExitFlags app.LatPressureLUTData.mzExitFlags], [1 2 3]), 'all');
                nTotalFits = numel([app.LatCamberLUTData.nfyExitFlags app.LatCamberLUTData.mzExitFlags]) + ...
                    numel([app.LatPressureLUTData.nfyExitFlags app.LatPressureLUTData.mzExitFlags]);
            end
            
            app.updateProgressDialogPrimaryMessage("Finished with " + nSuccess + "/" + nTotalFits + " successful Pacejka fits");
            % Re-enable all relevant ui elements
            app.RunOptionsPanel.Enable = 'on';
            app.TabGroup.Visible = true;
            app.TireInformationPanel.Visible = true;
            updateUI(app);
            
            if app.SaveFyandMzdatainLUTCheckBox.Value
                saX = deg2rad(app.RunOptions.SASampleVals);
                loadY = app.RunOptions.LoadSampleVals;
                if app.PacejkaIAorPState == CamberOrPressureState.Both
                    latCoeff = app.LatLUTData.nfyVals(:, :, 1, 3);
                    aligningTorque = app.LatLUTData.mzVals(:, :, 1, 3);
                else
                    latCoeff = app.LatCamberLUTData.nfyVals(:, :, 1);
                    aligningTorque = app.LatCamberLUTData.mzVals(:, :, 1);
                end
                
                save('lateral_data_lut.mat', 'saX', 'loadY', 'latCoeff', 'aligningTorque');
            end
            
            app.CurrentProgressDialog.close();
        end

        % Value changed function: PacejkaVaryDropdown
        function pacejkaVaryDropdownChanged(app, event)
            updatePacejkaStateFromDropdown(app);
            updatePacejkaDropdowns(app);
            updatePacejkaAxes(app);
        end

        % Value changed function: PacejkaIADropdown
        function pacejkaIADropdownChanged(app, event)
            updatePacejkaAxes(app);
        end

        % Value changed function: PacejkaPDropdown
        function pacejkaPDropdownChanged(app, event)
            updatePacejkaAxes(app);
        end

        % Value changed function: PacejkaFZDropdown
        function pacejkaFZDropdownChanged(app, event)
            updatePacejkaAxes(app);
        end

        % Value changed function: FZVaryDropdown
        function loadSensVaryDropdownChanged(app, event)
            updateLoadSensStateFromDropdown(app);
            updateLoadSensDropdowns(app);
            updateLoadSensAxesAuto(app);
        end

        % Value changed function: FZIADropdown
        function loadSensIADropdownChanged(app, event)
            updateLoadSensAxesAuto(app);
        end

        % Value changed function: FZPDropdown
        function loadSensPDropdownChanged(app, event)
            updateLoadSensAxesAuto(app);
        end

        % Callback function
        function loadSensSAdirChanged(app, event)
            updateLoadSensAxesAuto(app);
        end

        % Value changed function: SASlider
        function loadSensSASliderChanged(app, event)
            newVal = event.Value;
            updateLoadSensAxesWithRound(app, newVal);
        end

        % Value changed function: ShowFitOptionsButton
        function fitOptionsButtonValueChanged(app, event)
            value = app.ShowFitOptionsButton.Value;
            app.FitOptionsPanel.Visible = value;
        end

        % Value changed function: SimilarityVaryDropdown
        function similarityVaryDropdownChanged(app, event)
            updateSimilarityStateFromDropdown(app);
            updateSimilarityDropdowns(app);
            updateSimilarityAxes(app);
        end

        % Value changed function: SimilarityIADropdown
        function similarityIADropdownChanged(app, event)
            updateSimilarityAxes(app);
        end

        % Value changed function: SimilarityPDropdown
        function similarityPDropdownChanged(app, event)
            updateSimilarityAxes(app);
        end

        % Value changed function: SimilarityFZDropdown
        function similarityFZDropdownChanged(app, event)
            updateSimilarityAxes(app);
        end

        % Value changed function: SimilarityViewDropdown
        function SimilarityViewDropdownChanged(app, event)
            updateSimilarityAxes(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TireAnalyzerUIFigure = uifigure('Visible', 'off');
            app.TireAnalyzerUIFigure.Name = 'Tire Analyzer';
            app.TireAnalyzerUIFigure.Icon = 'RFRlogo.png';
            app.TotalGridLayout = uigridlayout(app.TireAnalyzerUIFigure);
            app.BottomGridLayout = uigridlayout(app.TotalGridLayout);
            app.RunOptionsPanel = uipanel(app.BottomGridLayout);
            app.GridLayout8 = uigridlayout(app.RunOptionsPanel);
            app.Panel_21 = uipanel(app.GridLayout8);
            app.CreatefitsCheckBox = uicheckbox(app.Panel_21);
            app.CreatefitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @createFitsCheckboxChanged, true);
            app.CreatefitsCheckBox.Text = 'Create fits';
            app.CreatefitsCheckBox.Value = true;
            app.ShowFitOptionsButton = uibutton(app.Panel_21, 'state');
            app.ShowFitOptionsButton.ValueChangedFcn = createCallbackFcn(app, @fitOptionsButtonValueChanged, true);
            app.ShowFitOptionsButton.Text = 'Show Fit Options';
            app.Panel_20 = uipanel(app.GridLayout8);
            app.SeparateSACheckBox = uicheckbox(app.Panel_20);
            app.SeparateSACheckBox.ValueChangedFcn = createCallbackFcn(app, @separateSAValueChanged, true);
            app.SeparateSACheckBox.Enable = 'off';
            app.SeparateSACheckBox.Text = 'Separate increasing/decreasing SA';
            app.Panel_19 = uipanel(app.GridLayout8);
            app.TireDropdown = uidropdown(app.Panel_19);
            app.TireDropdown.Items = {'-'};
            app.TireDropdown.ValueChangedFcn = createCallbackFcn(app, @tireDropdownChanged, true);
            app.TireDropdown.Value = '-';
            app.TireLabel = uilabel(app.Panel_19);
            app.TireLabel.Text = 'Tire:';
            app.Panel_11 = uipanel(app.GridLayout8);
            app.GridLayout7 = uigridlayout(app.Panel_11);
            app.TTCRoundCheckBox = uicheckbox(app.GridLayout7);
            app.TTCRoundCheckBox.Enable = 'off';
            app.TTCRoundCheckBox.Text = '';
            app.ManufacturerCheckBox = uicheckbox(app.GridLayout7);
            app.ManufacturerCheckBox.Enable = 'off';
            app.ManufacturerCheckBox.Text = '';
            app.TireDiameterCheckBox = uicheckbox(app.GridLayout7);
            app.TireDiameterCheckBox.Enable = 'off';
            app.TireDiameterCheckBox.Text = '';
            app.TireWidthCheckBox = uicheckbox(app.GridLayout7);
            app.TireWidthCheckBox.Enable = 'off';
            app.TireWidthCheckBox.Text = '';
            app.RimDiameterCheckBox = uicheckbox(app.GridLayout7);
            app.RimDiameterCheckBox.Enable = 'off';
            app.RimDiameterCheckBox.Text = '';
            app.RimWidthCheckBox = uicheckbox(app.GridLayout7);
            app.RimWidthCheckBox.Enable = 'off';
            app.RimWidthCheckBox.Text = '';
            app.CompoundCheckBox = uicheckbox(app.GridLayout7);
            app.CompoundCheckBox.Enable = 'off';
            app.CompoundCheckBox.Text = '';
            app.ManufacturerDropdownLabel = uilabel(app.GridLayout7);
            app.ManufacturerDropdownLabel.Enable = 'off';
            app.ManufacturerDropdownLabel.Text = 'Manufacturer:';
            app.ManufacturerDropdown = uidropdown(app.GridLayout7);
            app.ManufacturerDropdown.Items = {'-'};
            app.ManufacturerDropdown.Enable = 'off';
            app.ManufacturerDropdown.Value = '-';
            app.RoundDropdown = uidropdown(app.GridLayout7);
            app.RoundDropdown.Items = {'-'};
            app.RoundDropdown.Enable = 'off';
            app.RoundDropdown.Value = '-';
            app.TireDiameterDropdownLabel = uilabel(app.GridLayout7);
            app.TireDiameterDropdownLabel.Enable = 'off';
            app.TireDiameterDropdownLabel.Text = 'Tire diameter';
            app.TireDiameterDropdown = uidropdown(app.GridLayout7);
            app.TireDiameterDropdown.Items = {'-'};
            app.TireDiameterDropdown.Enable = 'off';
            app.TireDiameterDropdown.Value = '-';
            app.TireWidthDropdownLabel = uilabel(app.GridLayout7);
            app.TireWidthDropdownLabel.Enable = 'off';
            app.TireWidthDropdownLabel.Text = 'Tire width:';
            app.TireWidthDropdown = uidropdown(app.GridLayout7);
            app.TireWidthDropdown.Items = {'-'};
            app.TireWidthDropdown.Enable = 'off';
            app.TireWidthDropdown.Value = '-';
            app.RimDiameterDropdownLabel = uilabel(app.GridLayout7);
            app.RimDiameterDropdownLabel.Enable = 'off';
            app.RimDiameterDropdownLabel.Text = 'Rim diameter';
            app.RimDiameterDropdown = uidropdown(app.GridLayout7);
            app.RimDiameterDropdown.Items = {'-'};
            app.RimDiameterDropdown.Enable = 'off';
            app.RimDiameterDropdown.Value = '-';
            app.RimWidthDropdown = uidropdown(app.GridLayout7);
            app.RimWidthDropdown.Items = {'-'};
            app.RimWidthDropdown.Enable = 'off';
            app.RimWidthDropdown.Value = '-';
            app.CompoundDropdownLabel = uilabel(app.GridLayout7);
            app.CompoundDropdownLabel.Enable = 'off';
            app.CompoundDropdownLabel.Text = 'Compound:';
            app.CompoundDropdown = uidropdown(app.GridLayout7);
            app.CompoundDropdown.Items = {'-'};
            app.CompoundDropdown.Enable = 'off';
            app.CompoundDropdown.Value = '-';
            app.TTCRoundDropdownLabel = uilabel(app.GridLayout7);
            app.TTCRoundDropdownLabel.Enable = 'off';
            app.TTCRoundDropdownLabel.Text = 'TTC round';
            app.RimWidthDropdownLabel = uilabel(app.GridLayout7);
            app.RimWidthDropdownLabel.Enable = 'off';
            app.RimWidthDropdownLabel.Text = 'Rim width:';
            app.ConstructionDropdownLabel = uilabel(app.GridLayout7);
            app.ConstructionDropdownLabel.Enable = 'off';
            app.ConstructionDropdownLabel.Text = 'Construction';
            app.ConstructionDropdown = uidropdown(app.GridLayout7);
            app.ConstructionDropdown.Items = {'-'};
            app.ConstructionDropdown.Enable = 'off';
            app.ConstructionDropdown.Value = '-';
            app.CheckBox = uicheckbox(app.GridLayout7);
            app.CheckBox.Enable = 'off';
            app.CheckBox.Text = '';
            app.TireSelectionMethodButtonGroup = uibuttongroup(app.GridLayout8);
            app.TireSelectionMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @tireSelectionMethodChanged, true);
            app.FromdropdownButton = uiradiobutton(app.TireSelectionMethodButtonGroup);
            app.FromdropdownButton.Text = 'From dropdown';
            app.FromdropdownButton.Value = true;
            app.IndividualparametersButton = uiradiobutton(app.TireSelectionMethodButtonGroup);
            app.IndividualparametersButton.Enable = 'off';
            app.IndividualparametersButton.Text = 'Individual parameters';
            app.ScalingfactorsPanel = uipanel(app.GridLayout8);
            app.ScalingfactorsPanel.Title = 'Scaling factors';
            app.UsedifferentXandYscalingfactorsCheckBox = uicheckbox(app.ScalingfactorsPanel);
            app.UsedifferentXandYscalingfactorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @useDifferentScalingFactorsChanged, true);
            app.UsedifferentXandYscalingfactorsCheckBox.Text = 'Use different X and Y scaling factors';
            app.ScalingfactorLabel = uilabel(app.ScalingfactorsPanel);
            app.ScalingfactorLabel.Text = 'Scaling factor:';
            app.XscalingfactorEditField = uieditfield(app.ScalingfactorsPanel, 'numeric');
            app.XscalingfactorEditField.Limits = [0 Inf];
            app.XscalingfactorEditField.Value = 1;
            app.YscalingfactorLabel = uilabel(app.ScalingfactorsPanel);
            app.YscalingfactorLabel.Visible = 'off';
            app.YscalingfactorLabel.Text = 'Y scaling factor:';
            app.YscalingfactorEditField = uieditfield(app.ScalingfactorsPanel, 'numeric');
            app.YscalingfactorEditField.Limits = [0 Inf];
            app.YscalingfactorEditField.Visible = 'off';
            app.YscalingfactorEditField.Value = 1;
            app.GridLayout16 = uigridlayout(app.GridLayout8);
            app.RunButton = uibutton(app.GridLayout16, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.TirenotsupportedyetLabel = uilabel(app.GridLayout8);
            app.TirenotsupportedyetLabel.Visible = 'off';
            app.TirenotsupportedyetLabel.Text = '(!)  Tire not supported yet    ';
            app.TireInformationPanel = uipanel(app.BottomGridLayout);
            app.TireInformationPanel.Title = 'Tire Information';
            app.TireInfoPanelGridLayout = uigridlayout(app.TireInformationPanel);
            app.TireInfoLabelsGridLayout = uigridlayout(app.TireInfoPanelGridLayout);
            app.TTCRoundLabel = uilabel(app.TireInfoLabelsGridLayout);
            app.TTCRoundLabel.Text = 'TTC round';
            app.TTCRoundValueLabel = uilabel(app.TireInfoLabelsGridLayout);
            app.TTCRoundValueLabel.Text = '0';
            app.TireWidthLabel = uilabel(app.TireInfoLabelsGridLayout);
            app.TireWidthLabel.Text = 'Tire width';
            app.TireWidthValueLabel = uilabel(app.TireInfoLabelsGridLayout);
            app.TireWidthValueLabel.Text = '0';
            app.ManufacturerLabel = uilabel(app.TireInfoLabelsGridLayout);
            app.ManufacturerLabel.Text = 'Manufacturer';
            app.ManufacturerValueLabel = uilabel(app.TireInfoLabelsGridLayout);
            app.ManufacturerValueLabel.Text = '0';
            app.TireDiameterLabel = uilabel(app.TireInfoLabelsGridLayout);
            app.TireDiameterLabel.Text = 'Tire diameter';
            app.TireDiameterValueLabel = uilabel(app.TireInfoLabelsGridLayout);
            app.TireDiameterValueLabel.Text = '0';
            app.RimDiameterLabel = uilabel(app.TireInfoLabelsGridLayout);
            app.RimDiameterLabel.Text = 'Rim diameter';
            app.RimWidthLabel = uilabel(app.TireInfoLabelsGridLayout);
            app.RimWidthLabel.Text = 'Rim width';
            app.ConstructionLabel = uilabel(app.TireInfoLabelsGridLayout);
            app.ConstructionLabel.Text = 'Construction';
            app.CompoundLabel = uilabel(app.TireInfoLabelsGridLayout);
            app.CompoundLabel.Text = 'Compound';
            app.RimDiameterValueLabel = uilabel(app.TireInfoLabelsGridLayout);
            app.RimDiameterValueLabel.Text = '0';
            app.RimWidthValueLabel = uilabel(app.TireInfoLabelsGridLayout);
            app.RimWidthValueLabel.Text = '0';
            app.ConstructionValueLabel = uilabel(app.TireInfoLabelsGridLayout);
            app.ConstructionValueLabel.Text = '0';
            app.CompoundValueLabel = uilabel(app.TireInfoLabelsGridLayout);
            app.CompoundValueLabel.Text = '0';
            app.ErrorLabel = uilabel(app.TireInfoPanelGridLayout);
            app.ErrorLabel.Text = 'Error';
            app.TestFormatPanel = uipanel(app.TireInfoPanelGridLayout);
            app.TestFormatPanel.Title = 'Test Format';
            app.TestSectionTree = uitree(app.TestFormatPanel);
            app.TireNameLabel = uilabel(app.TireInfoPanelGridLayout);
            app.TireNameLabel.Text = 'Tire name';
            app.TabGroup = uitabgroup(app.BottomGridLayout);
            app.PacejkaFitsTab = uitab(app.TabGroup);
            app.PacejkaFitsTab.Title = 'Pacejka Fits';
            app.GridLayout21 = uigridlayout(app.PacejkaFitsTab);
            app.PacejkaFitAxes = uiaxes(app.GridLayout21);
            title(app.PacejkaFitAxes, 'Pacejka fit')
            xlabel(app.PacejkaFitAxes, 'Slip angle (deg)')
            ylabel(app.PacejkaFitAxes, 'Lateral coefficient')
            app.GridLayout4 = uigridlayout(app.GridLayout21);
            app.Panel_24 = uipanel(app.GridLayout4);
            app.PacejkaPDropdown = uidropdown(app.Panel_24);
            app.PacejkaPDropdown.Items = {'-'};
            app.PacejkaPDropdown.ValueChangedFcn = createCallbackFcn(app, @pacejkaPDropdownChanged, true);
            app.PacejkaPDropdown.Value = '-';
            app.PacejkaPDropdownLabel = uilabel(app.Panel_24);
            app.PacejkaPDropdownLabel.Text = 'P (psi):';
            app.Panel_25 = uipanel(app.GridLayout4);
            app.PacejkaFZDropdown = uidropdown(app.Panel_25);
            app.PacejkaFZDropdown.Items = {'-'};
            app.PacejkaFZDropdown.ValueChangedFcn = createCallbackFcn(app, @pacejkaFZDropdownChanged, true);
            app.PacejkaFZDropdown.Value = '-';
            app.PacejkaFZDropdownLabel = uilabel(app.Panel_25);
            app.PacejkaFZDropdownLabel.Text = 'FZ (N):';
            app.Panel_26 = uipanel(app.GridLayout4);
            app.PacejkaIADropdown = uidropdown(app.Panel_26);
            app.PacejkaIADropdown.Items = {'-'};
            app.PacejkaIADropdown.ValueChangedFcn = createCallbackFcn(app, @pacejkaIADropdownChanged, true);
            app.PacejkaIADropdown.Value = '-';
            app.PacejkaIADropdownLabel = uilabel(app.Panel_26);
            app.PacejkaIADropdownLabel.Text = 'IA (deg):';
            app.Panel_27 = uipanel(app.GridLayout4);
            app.PacejkaVaryDropdownLabel = uilabel(app.Panel_27);
            app.PacejkaVaryDropdownLabel.Text = 'Vary:';
            app.PacejkaVaryDropdown = uidropdown(app.Panel_27);
            app.PacejkaVaryDropdown.Items = {'IA', 'P'};
            app.PacejkaVaryDropdown.ValueChangedFcn = createCallbackFcn(app, @pacejkaVaryDropdownChanged, true);
            app.PacejkaVaryDropdown.Value = 'IA';
            app.Panel_9 = uipanel(app.GridLayout4);
            app.PacejkaSAdirDropdown = uidropdown(app.Panel_9);
            app.PacejkaSAdirDropdown.Items = {'+', '-'};
            app.PacejkaSAdirDropdown.Enable = 'off';
            app.PacejkaSAdirDropdown.Value = '+';
            app.PacejkaSAdirDropdownLabel = uilabel(app.Panel_9);
            app.PacejkaSAdirDropdownLabel.Enable = 'off';
            app.PacejkaSAdirDropdownLabel.Text = 'SA dir:';
            app.PacejkaFitNotCompletedLabel = uilabel(app.GridLayout21);
            app.PacejkaFitNotCompletedLabel.Enable = 'off';
            app.PacejkaFitNotCompletedLabel.Text = 'Fit not completed        ';
            app.LoadSensitivityFitsTab = uitab(app.TabGroup);
            app.LoadSensitivityFitsTab.Title = 'Load Sensitivity Fits';
            app.GridLayout2 = uigridlayout(app.LoadSensitivityFitsTab);
            app.LoadSensAxes = uiaxes(app.GridLayout2);
            title(app.LoadSensAxes, 'Load sensitivity and fit at SA=Xdeg')
            xlabel(app.LoadSensAxes, 'Load (N)')
            ylabel(app.LoadSensAxes, 'Lateral coefficient')
            app.LoadSensAxes.YLim = [-3.2 3.2];
            app.GridLayout2_2 = uigridlayout(app.GridLayout2);
            app.Panel_30 = uipanel(app.GridLayout2_2);
            app.FZPDropdownLabel = uilabel(app.Panel_30);
            app.FZPDropdownLabel.Text = 'P (psi):';
            app.FZPDropdown = uidropdown(app.Panel_30);
            app.FZPDropdown.Items = {'10', '12', '14'};
            app.FZPDropdown.ValueChangedFcn = createCallbackFcn(app, @loadSensPDropdownChanged, true);
            app.FZPDropdown.Value = '10';
            app.Panel_29 = uipanel(app.GridLayout2_2);
            app.FZIADropdownLabel = uilabel(app.Panel_29);
            app.FZIADropdownLabel.Text = 'IA (deg):';
            app.FZIADropdown = uidropdown(app.Panel_29);
            app.FZIADropdown.Items = {'0', '2', '4'};
            app.FZIADropdown.ValueChangedFcn = createCallbackFcn(app, @loadSensIADropdownChanged, true);
            app.FZIADropdown.Value = '0';
            app.Panel_28 = uipanel(app.GridLayout2_2);
            app.FZVaryDropdownLabel = uilabel(app.Panel_28);
            app.FZVaryDropdownLabel.Text = 'Vary:';
            app.FZVaryDropdown = uidropdown(app.Panel_28);
            app.FZVaryDropdown.Items = {'IA', 'P'};
            app.FZVaryDropdown.ValueChangedFcn = createCallbackFcn(app, @loadSensVaryDropdownChanged, true);
            app.FZVaryDropdown.Value = 'IA';
            app.Panel_31 = uipanel(app.GridLayout2_2);
            app.FZSAdirDropdownLabel = uilabel(app.Panel_31);
            app.FZSAdirDropdownLabel.Enable = 'off';
            app.FZSAdirDropdownLabel.Text = 'SA dir:';
            app.FZSAdirDropdown = uidropdown(app.Panel_31);
            app.FZSAdirDropdown.Items = {'+', '-'};
            app.FZSAdirDropdown.Enable = 'off';
            app.FZSAdirDropdown.Value = '+';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.LBdegLabel = uilabel(app.GridLayout6);
            app.LBdegLabel.Text = '-15 deg';
            app.SASlider = uislider(app.GridLayout6);
            app.SASlider.Limits = [-15 15];
            app.SASlider.MajorTicks = [-15 0 15];
            app.SASlider.MajorTickLabels = {''};
            app.SASlider.ValueChangedFcn = createCallbackFcn(app, @loadSensSASliderChanged, true);
            app.UBdegLabel = uilabel(app.GridLayout6);
            app.UBdegLabel.Text = '15 deg';
            app.SimilarityMethodTab = uitab(app.TabGroup);
            app.SimilarityMethodTab.Title = 'Similarity Method';
            app.GridLayout25 = uigridlayout(app.SimilarityMethodTab);
            app.SimilarityAxes = uiaxes(app.GridLayout25);
            title(app.SimilarityAxes, 'Similarity Method Results')
            xlabel(app.SimilarityAxes, 'Slip Angle (deg)')
            ylabel(app.SimilarityAxes, 'Lateral Force (N)')
            zlabel(app.SimilarityAxes, 'Z')
            app.GridLayout4_2 = uigridlayout(app.GridLayout25);
            app.Panel_42 = uipanel(app.GridLayout4_2);
            app.SimilarityPDropdown = uidropdown(app.Panel_42);
            app.SimilarityPDropdown.Items = {'-'};
            app.SimilarityPDropdown.ValueChangedFcn = createCallbackFcn(app, @similarityPDropdownChanged, true);
            app.SimilarityPDropdown.Value = '-';
            app.SimilarityPDropdownLabel = uilabel(app.Panel_42);
            app.SimilarityPDropdownLabel.Text = 'P (psi):';
            app.Panel_43 = uipanel(app.GridLayout4_2);
            app.SimilarityFZDropdown = uidropdown(app.Panel_43);
            app.SimilarityFZDropdown.Items = {'-'};
            app.SimilarityFZDropdown.ValueChangedFcn = createCallbackFcn(app, @similarityFZDropdownChanged, true);
            app.SimilarityFZDropdown.Value = '-';
            app.SimilarityFZDropdownLabel = uilabel(app.Panel_43);
            app.SimilarityFZDropdownLabel.Text = 'FZ (N):';
            app.Panel_44 = uipanel(app.GridLayout4_2);
            app.SimilarityIADropdown = uidropdown(app.Panel_44);
            app.SimilarityIADropdown.Items = {'-'};
            app.SimilarityIADropdown.ValueChangedFcn = createCallbackFcn(app, @similarityIADropdownChanged, true);
            app.SimilarityIADropdown.Value = '-';
            app.SimilarityIADropdownLabel = uilabel(app.Panel_44);
            app.SimilarityIADropdownLabel.Text = 'IA (deg):';
            app.Panel_45 = uipanel(app.GridLayout4_2);
            app.SimilarityVaryDropdownLabel = uilabel(app.Panel_45);
            app.SimilarityVaryDropdownLabel.Text = 'Vary:';
            app.SimilarityVaryDropdown = uidropdown(app.Panel_45);
            app.SimilarityVaryDropdown.Items = {'IA', 'P'};
            app.SimilarityVaryDropdown.ValueChangedFcn = createCallbackFcn(app, @similarityVaryDropdownChanged, true);
            app.SimilarityVaryDropdown.Value = 'IA';
            app.Panel_46 = uipanel(app.GridLayout4_2);
            app.SimilaritySAdirDropdown = uidropdown(app.Panel_46);
            app.SimilaritySAdirDropdown.Items = {'+', '-'};
            app.SimilaritySAdirDropdown.Enable = 'off';
            app.SimilaritySAdirDropdown.Value = '+';
            app.SimilaritySAdirDropdownLabel = uilabel(app.Panel_46);
            app.SimilaritySAdirDropdownLabel.Enable = 'off';
            app.SimilaritySAdirDropdownLabel.Text = 'SA dir:';
            app.Panel_47 = uipanel(app.GridLayout4_2);
            app.ViewDropDownLabel = uilabel(app.Panel_47);
            app.ViewDropDownLabel.Text = 'View:';
            app.SimilarityViewDropdown = uidropdown(app.Panel_47);
            app.SimilarityViewDropdown.Items = {'FY', 'MZ'};
            app.SimilarityViewDropdown.ValueChangedFcn = createCallbackFcn(app, @SimilarityViewDropdownChanged, true);
            app.SimilarityViewDropdown.Value = 'FY';
            app.TopGridLayout = uigridlayout(app.TotalGridLayout);
            app.Panel_22 = uipanel(app.TopGridLayout);
            app.GridLayout17 = uigridlayout(app.Panel_22);
            app.SeesourcecodeLabel = uilabel(app.GridLayout17);
            app.SeesourcecodeLabel.Text = 'See source code:';
            app.SourceCodeHyperlink = uihyperlink(app.GridLayout17);
            app.SourceCodeHyperlink.URL = 'https://github.com/yafta98/TireAnalyzer';
            app.SourceCodeHyperlink.Text = 'https://github.com/yafta98/TireAnalyzer';
            app.GridLayout18 = uigridlayout(app.TopGridLayout);
            app.TIREANALYZERLabel = uilabel(app.GridLayout18);
            app.TIREANALYZERLabel.Text = 'TIRE ANALYZER';
            app.RFRImage = uiimage(app.TopGridLayout);
            app.RFRImage.ImageSource = 'RFRlogo.png';
            app.GridLayout19 = uigridlayout(app.TopGridLayout);
            app.ByMatthewDrummondStoylesandDanielVenturaLabel = uilabel(app.GridLayout19);
            app.ByMatthewDrummondStoylesandDanielVenturaLabel.Text = 'By Matthew Drummond-Stoyles and Daniel Ventura';
            app.FitOptionsPanel = uipanel(app.TireAnalyzerUIFigure);
            app.FitOptionsPanel.Title = 'Fit Options';
            app.GridLayout22 = uigridlayout(app.FitOptionsPanel);
            app.GridLayout20 = uigridlayout(app.GridLayout22);
            app.LoadSensitivityPolynomialFitsPanel = uipanel(app.GridLayout20);
            app.LoadSensitivityPolynomialFitsPanel.Title = 'Load Sensitivity Polynomial Fits';
            app.GridLayout23 = uigridlayout(app.LoadSensitivityPolynomialFitsPanel);
            app.Panel_32 = uipanel(app.GridLayout23);
            app.LoadsensitivityorderLabel = uilabel(app.Panel_32);
            app.LoadsensitivityorderLabel.Text = 'Load sensitivity order:';
            app.LoadSensitivityOrderSpinner = uispinner(app.Panel_32);
            app.LoadSensitivityOrderSpinner.Limits = [1 4];
            app.LoadSensitivityOrderSpinner.Tooltip = {'Polynomial order that will be fit to the load sensitivity data.'};
            app.LoadSensitivityOrderSpinner.Value = 2;
            app.Panel_33 = uipanel(app.GridLayout23);
            app.LoadupperboundNEditFieldLabel = uilabel(app.Panel_33);
            app.LoadupperboundNEditFieldLabel.Text = 'Load upper bound (N):';
            app.LoadUBEditField = uieditfield(app.Panel_33, 'numeric');
            app.LoadUBEditField.Value = 1200;
            app.Panel_34 = uipanel(app.GridLayout23);
            app.LoadstepsizeNEditFieldLabel = uilabel(app.Panel_34);
            app.LoadstepsizeNEditFieldLabel.Text = 'Load step size (N):';
            app.LoadStepSizeEditField = uieditfield(app.Panel_34, 'numeric');
            app.LoadStepSizeEditField.Value = 20;
            app.Panel_35 = uipanel(app.GridLayout23);
            app.OldPacejkaFitsPanel = uipanel(app.GridLayout20);
            app.OldPacejkaFitsPanel.Title = 'Old Pacejka Fits';
            app.GridLayout24 = uigridlayout(app.OldPacejkaFitsPanel);
            app.Panel_36 = uipanel(app.GridLayout24);
            app.SlipanglestepsizedegEditFieldLabel = uilabel(app.Panel_36);
            app.SlipanglestepsizedegEditFieldLabel.Text = 'Slip angle step size (deg):';
            app.SAStepSizeEditField = uieditfield(app.Panel_36, 'numeric');
            app.SAStepSizeEditField.Value = 0.1;
            app.Panel_37 = uipanel(app.GridLayout24);
            app.SlipanglebounddegLabel = uilabel(app.Panel_37);
            app.SlipanglebounddegLabel.Text = 'Slip angle bound (deg):';
            app.SABoundEditField = uieditfield(app.Panel_37, 'numeric');
            app.SABoundEditField.Value = 15;
            app.Panel_38 = uipanel(app.GridLayout24);
            app.FitalgorithmLabel = uilabel(app.Panel_38);
            app.FitalgorithmLabel.Text = 'Fit algorithm:';
            app.FitalgorithmDropdown = uidropdown(app.Panel_38);
            app.FitalgorithmDropdown.Items = {'trust-region-reflective', 'levenberg-marquardt'};
            app.FitalgorithmDropdown.Value = 'levenberg-marquardt';
            app.Panel_39 = uipanel(app.GridLayout24);
            app.FitmaxiterationsEditFieldLabel = uilabel(app.Panel_39);
            app.FitmaxiterationsEditFieldLabel.Text = 'Fit max iterations:';
            app.FitmaxiterationsEditField = uieditfield(app.Panel_39, 'numeric');
            app.FitmaxiterationsEditField.Value = 3000;
            app.Panel_40 = uipanel(app.GridLayout24);
            app.SaveFyandMzdatainLUTCheckBox = uicheckbox(app.Panel_40);
            app.SaveFyandMzdatainLUTCheckBox.Text = 'Save Fy and Mz data in LUT';
            app.Panel_41 = uipanel(app.GridLayout24);
            app.PacejkaSimilarityMethodPanel = uipanel(app.GridLayout20);
            app.PacejkaSimilarityMethodPanel.Title = 'Pacejka Similarity Method';
            app.GridLayout26 = uigridlayout(app.PacejkaSimilarityMethodPanel);
            app.Panel_48 = uipanel(app.GridLayout26);
            app.GridLayout27 = uigridlayout(app.Panel_48);
            app.LoadAdjustmentCheckbox = uicheckbox(app.GridLayout27);
            app.LoadAdjustmentCheckbox.Text = 'Load adjustment';
            app.LoadAdjustmentCheckbox.Value = true;
            app.StiffnessAdjustmentCheckbox = uicheckbox(app.GridLayout27);
            app.StiffnessAdjustmentCheckbox.Text = 'Stiffness adjustment';
            app.StiffnessAdjustmentCheckbox.Value = true;
            app.CoFAdjustmentCheckbox = uicheckbox(app.GridLayout27);
            app.CoFAdjustmentCheckbox.Text = 'CoF adjustment';
            app.CoFAdjustmentCheckbox.Value = true;
            app.CamberShiftCheckbox = uicheckbox(app.GridLayout27);
            app.CamberShiftCheckbox.Text = 'Camber shift';
            app.CamberShiftCheckbox.Value = true;
            % Show the figure after all components are created
            app.TireAnalyzerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TireAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TireAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TireAnalyzerUIFigure)
        end
    end
end